classdef binselectorGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BinSelectorUIFigure         matlab.ui.Figure
        UITable                     matlab.ui.control.Table
        ChoosewhichbinstodecodeacrossLabel  matlab.ui.control.Label
        CreateBESTmatButton         matlab.ui.control.Button
        UITable2                    matlab.ui.control.Table
        Label                       matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        OptionalFrequencyTransformPanel  matlab.ui.container.Panel
        Label_3                     matlab.ui.control.Label
        TFCheckBox                  matlab.ui.control.CheckBox
        Label_4                     matlab.ui.control.Label
        LowEdgeFrequencyinPassBandEditFieldLabel  matlab.ui.control.Label
        LowEdgeFrequencyinPassBandEditField  matlab.ui.control.EditField
        HighEdgeFrequencyinPassBandEditFieldLabel  matlab.ui.control.Label
        HighEdgeFrequencyinPassBandEditField  matlab.ui.control.EditField
        CurrentSamplingRateHzLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        SelectedCells; % Description
        output; 
        FinishButton; % Description
        LoadedBins; % Description
        FS; % Description
        ChangeFS; % Description
        ApplyBP; % Description
        BP_Freq; % Description
        EEG % Description
        time_limit % Description
        times % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            %inputs 
            ALLEEG = varargin{1}; %EEGstruct
            currset = varargin{2}; %current Set
            binsel = varargin{3}{1}'; % bins (working memory)
            cmk_bp = varargin{3}{2}; %apply bandpass
            BPfreq = varargin{3}{3}; %band pass frequencies
           
            %% Obtain valid epoched eegsets
            %% only one at a time
            app.EEG = ALLEEG(currset); 
            n_eeg = numel(ALLEEG);
            valid_eeg = zeros(n_eeg,1); %index
            valid_eeg_logical = zeros(n_eeg,1); %for GUI             
         
            for i = 1:n_eeg
                    
                if ~isempty(ALLEEG(i).epoch)
                    
                    valid_eeg(i) = i;
                    valid_eeg_logical(i) = 1; 
                    
                else
                    valid_eeg_logical(i) = 0; 
                    
                end
                
            end
            
            
            %add to table component (list ACTIVE ONLY)
            EEGnames = {ALLEEG(currset).setname};
            
            %cnames = table(EEGnames, valid_eeg_logical,bin_check);
            app.UITable2.Data = EEGnames; % OneSet
            
            % Update table component 
            newdata = ALLEEG(currset).EVENTLIST.bdf;
            binlist = {newdata.description}';
            binind = {newdata.namebin}';
            combine_bins = strcat(binind,' ---  ',binlist);
            
            tdata = table(combine_bins);
            app.UITable.Data = tdata;
            set(app.UITable,'SelectionType','row');
            set(app.UITable,'Multiselect','on');

            try             
                % select the currently loaded & valid eegset

                set(app.UITable,'Selection',binsel); %ONE EEGSET loaded
                app.LoadedBins = binsel; 
                
            catch % if working memeory can't find bins, default to first bin only

                set(app.UITable,'Selection',1); %ONE EEGSET loaded
                app.LoadedBins = binsel; 
                
            end
                
            % set current sampling rate  window
            FS = ALLEEG(currset).srate; 
%             set(app.CurrentSamplingRateHzEditField,'Editable','off');
%             set(app.CurrentSamplingRateHzEditField,'Enable','on');
            time_sample = (1/FS); %in ms
          %  set(app.whichequalsEditField,'Value',time_sample*1000);
%             set(app.whichequalsEditField,'Editable','off');
%             set(app.whichequalsEditField,'Enable','on');
            FS_str = sprintf(['Current Sampling Rate is %i (Hz) which equals' ...
                ' %.4f ms per sample point'], FS, time_sample*1000); 
            set(app.CurrentSamplingRateHzLabel,'Text', FS_str);
            

            %% DEFUNCT resampling option
            %time_sample_r = round(time_sample,3);
%             time_limit = [time_sample abs(ALLEEG(currset).xmin)+ALLEEG(currset).xmax] * 1000; %in ms
%             %time_limit is not epoch limits; its available re-sampling steps
%             app.time_limit = time_limit; 
%             app.times = [0:time_limit(1):time_limit(end)-time_limit(1)]; 
            %times is epoch limit (but not adjusted for true epoch times)
            %think of this sample points converted to ms, but not
            %adjusted for true epoch times (like prestim baseline,etc)
            %AND it is always missing the sample-point at the end of epoch due
            %to 0 indexing (i.e. sample point 1 is the timepoint at the
            %beginning of epoch


%             if cmk_fs == 1
%                 app.FS = FS;
%                 set(app.PerformEpochReSamplingCheckBox, 'Value',1);
% %                 set(app.TPspinner,'Editable','on');
% %                 set(app.TPspinner,'Enable','on');
% %                 set(app.TPspinner,'Value',time_limit(1)); 
% %                 set(app.TPspinner,'Limits',time_limit);
% %                 set(app.TPspinner,'Step', time_limit(1)); 
% %                 set(app.SamplingRateNew,'Value', FS)
%                 set(app.SamplingRateNew,'BackgroundColor',[0.5 0.5 0.5]); 
%                 set(app.SamplingRateNew,'Editable','off');
%                 set(app.SamplingRateNew,'Enable','off');
%                
%             else
%                 app.FS = FS; 
%                 set(app.PerformEpochReSamplingCheckBox, 'Value',0);
% %                 set(app.TPspinner,'Editable','off');
% %                 set(app.TPspinner,'Enable','off'); 
% %                 set(app.TPspinner,'Value',time_limit(1)); 
% %                 set(app.TPspinner,'Limits',time_limit);
% %                 set(app.TPspinner,'Step', time_limit(1)); 
% %                 set(app.SamplingRateNew,'Value', FS)
%                 set(app.SamplingRateNew,'BackgroundColor',[0 0 0]); 
%                 set(app.SamplingRateNew,'Editable','off');
%                 set(app.SamplingRateNew,'Enable','off');
%  
%             
%             end
            
            %set frequncy transform
            app.ApplyBP = cmk_bp;
            app.BP_Freq = BPfreq; 
            
            
            set(app.LowEdgeFrequencyinPassBandEditField,'Value', num2str(BPfreq{1}));
            set(app.HighEdgeFrequencyinPassBandEditField,'Value', num2str(BPfreq{2}));
            
            if cmk_bp == 1
                set(app.TFCheckBox,'Value',1); 
                
                set(app.LowEdgeFrequencyinPassBandEditField,'Editable','on');
                set(app.HighEdgeFrequencyinPassBandEditField,'Editable','on');
                set(app.LowEdgeFrequencyinPassBandEditField,'Enable','on');
                set(app.HighEdgeFrequencyinPassBandEditField,'Enable','on');
                
            else
                set(app.TFCheckBox,'Value',0);
                
                set(app.LowEdgeFrequencyinPassBandEditField,'Editable','off');
                set(app.HighEdgeFrequencyinPassBandEditField,'Editable','off');
                set(app.LowEdgeFrequencyinPassBandEditField,'Enable','off');
                set(app.HighEdgeFrequencyinPassBandEditField,'Enable','off');                
            end


        end

        % Callback function
        function UITableCellEdit(app, event)
            %indices = event.Indices;
            
        end

        % Cell selection callback: UITable, UITable2
        function UITableCellSelection(app, event)

            app.SelectedCells = event.Indices;
    
        end

        % Button pushed function: CreateBESTmatButton
        function CreateBESTmatButtonPushed(app, event)
            
            % for multiple bins across file feature
            
            if ~isempty(app.SelectedCells)
                binsel_final = app.SelectedCells(:,1);
                %app.SelectedBinsEditField.Value = vect2colon(binsel, 'Delimiter', 'off');
                
                
            else
                binsel_final = app.LoadedBins;
                %msgbox('No Bins Selected!', 'Whoops!'); %better to put output error
                
            end
            
            %output resampling 
%             RS_final_ms = app.TPspinner.Value/1000; %steps to resample (ms)
%             fs = app.FS; %original FS
%             samp_ms = 1/fs; %sample in ms 
%             RS_final = RS_final_ms/samp_ms; %resample factor (sample points)
%             %therefore, resample with every RS_final as the steps 
%             change_RS_final = app.ChangeFS;
            
            %output band pass
            bandpass_cmk = app.ApplyBP; 
            bandpass = app.BP_Freq; %cell contains either numerics (apply it) or empty str (don't apply)
            
            if bandpass_cmk == 1
                test_bp = cellfun(@isempty,bandpass); 
                             
                if test_bp(1) == 1
                    msgboxText = 'Cannot leave Low Edge Frequency field empty';
                    title_msg  = 'ERPLAB: pop_binselector() error:';
                    errorfound(msgboxText, title_msg);
                    return
                elseif test_bp(2) == 1
                    msgboxText = 'Cannot leave High Edge Frequency field empty';
                    title_msg  = 'ERPLAB: pop_binselector() error:';
                    errorfound(msgboxText, title_msg);
                    return
                end

            end

            
            %outputs = {binsel_final,change_RS_final,RS_final, bandpass_cmk, bandpass};
            outputs = {binsel_final, bandpass_cmk, bandpass};
            
            app.output = outputs; 
            app.FinishButton = 1;
   
        end

        % Close request function: BinSelectorUIFigure
        function BinSelectorUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Callback function
        function PerformEpochReSamplingCheckBoxValueChanged(app, event)
%             value = app.PerformEpochReSamplingCheckBox.Value;
%             time_limit = app.time_limit;
%             
%             if value == 1
%                 display('Resampling selected ')
%                 set(app.SamplingRateNew,'Editable','off');
%                 set(app.SamplingRateNew,'Enable','on'); 
%                 set(app.SamplingRateNew,'Value', app.FS)
%                 set(app.SamplingRateNew,'BackgroundColor',[0.5 0.5 0.5]); 
%                 set(app.TPspinner,'Editable','on');
%                 set(app.TPspinner,'Enable','on');
%                 set(app.TPspinner,'Limits',[time_limit]);
%                 set(app.TPspinner,'Value',time_limit(1)); 
%                 set(app.TPspinner,'Limits',time_limit);
%                 set(app.TPspinner,'Step', time_limit(1)); 
%                 
%             else
%                 display('Resampling not selected ')
%                 set(app.SamplingRateNew,'Editable','off');
%                 set(app.SamplingRateNew,'Enable','off');
%                 set(app.SamplingRateNew,'Value', app.FS);
%                 set(app.SamplingRateNew,'BackgroundColor',[0 0 0]); 
%                 set(app.TPspinner,'Value',time_limit(1)); 
%                 set(app.TPspinner,'Editable','off');
%                 set(app.TPspinner,'Enable','off');
%             end
%             
%             app.ChangeFS = value; 
            
            
        end

        % Callback function
        function SamplingRateNewValueChanged(app, event)
%             value = app.TPspinner.Value; %new resample value 
%             
%             %check value if valid
%             times = app.times;
%             time_limit = app.time_limit;
%             
%             if ~ismember(value,times)
%                 msgboxTest = sprintf('Selecting time-points: must be in units of %i ms. Will round!',time_limit(1));
%                 title = 'ERPLAB: wrong time-units';
%                 errorfound(msgboxTest, title);
%                 
%                 %find closest value rounded down
%                 [~,ind] = min(abs(times-value));
%                 value = times(ind);
%                 set(app.TPspinner,'Value',value);
%                 app.TPspinner.Value = value;
%             end
%             
%             npnts_old = app.EEG.pnts; 
%             fs = app.FS; 
%             samps = 1/fs; %granularity of data sample in ms 
%             epochtime = (samps*npnts_old) * 1000 ; %ms 
%             
%             npnts = epochtime/value; 
%             
%             new_FS = (npnts/epochtime * 1000);
%             
%             %save changes
%             set(app.SamplingRateNew, 'Value', new_FS); 
            
        end

        % Value changed function: TFCheckBox
        function TFCheckBoxValueChanged(app, event)
            value = app.TFCheckBox.Value;
            if value == 1
                set(app.LowEdgeFrequencyinPassBandEditField,'Editable','on');
                set(app.LowEdgeFrequencyinPassBandEditField,'Enable','on');
                set(app.HighEdgeFrequencyinPassBandEditField,'Editable','on');
                set(app.HighEdgeFrequencyinPassBandEditField,'Enable','on');
                
            else
                set(app.LowEdgeFrequencyinPassBandEditField,'Editable','off');
                set(app.LowEdgeFrequencyinPassBandEditField,'Enable','off');
                set(app.HighEdgeFrequencyinPassBandEditField,'Editable','off');
                set(app.HighEdgeFrequencyinPassBandEditField,'Enable','off');
            end
            
            app.ApplyBP = value;
        end

        % Value changed function: 
        % LowEdgeFrequencyinPassBandEditField
        function LowEdgeFrequencyinPassBandEditFieldValueChanged(app, event)
            value = app.LowEdgeFrequencyinPassBandEditField.Value;
            
            low_bp = str2num(value);
            
            if isempty(low_bp)
                msgboxText = 'Cannot leave Low Edge field empty';
                title_msg  = 'ERPLAB: pop_binselector() error:';
                errorfound(msgboxText, title_msg);
                return
            else
                app.BP_Freq{1} = low_bp; 
            end


        end

        % Value changed function: 
        % HighEdgeFrequencyinPassBandEditField
        function HighEdgeFrequencyinPassBandEditFieldValueChanged(app, event)
            value = app.HighEdgeFrequencyinPassBandEditField.Value;
            
            high_bp = str2num(value);
            
            if isempty(high_bp)
                msgboxText = 'Cannot leave High Edge field empty';
                title_msg  = 'ERPLAB: pop_binselector() error:';
                errorfound(msgboxText, title_msg);
                return
            else
                app.BP_Freq{2} = high_bp; 
            end
            
            
        end

        % Callback function
        function SRsliderValueChanged(app, event)
            value = app.SRslider.Value; 
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BinSelectorUIFigure = uifigure('Visible', 'off');
            app.BinSelectorUIFigure.Name = 'Bin Selector';
            app.BinSelectorUIFigure.CloseRequestFcn = createCallbackFcn(app, @BinSelectorUIFigureCloseRequest, true);
            app.UITable = uitable(app.BinSelectorUIFigure);
            app.UITable.ColumnName = {'Bin Number --- Bin Description'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.ChoosewhichbinstodecodeacrossLabel = uilabel(app.BinSelectorUIFigure);
            app.ChoosewhichbinstodecodeacrossLabel.Text = 'Choose which bins to decode across (Hold "SHIFT* if selecting multiple bins)';
            app.CreateBESTmatButton = uibutton(app.BinSelectorUIFigure, 'push');
            app.CreateBESTmatButton.ButtonPushedFcn = createCallbackFcn(app, @CreateBESTmatButtonPushed, true);
            app.CreateBESTmatButton.Text = 'Create BEST (.mat)';
            app.UITable2 = uitable(app.BinSelectorUIFigure);
            app.UITable2.ColumnName = {'Loaded EEGset File'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = [false false true];
            app.UITable2.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.Label = uilabel(app.BinSelectorUIFigure);
            app.Label.Text = 'Create bin-epoched single trial (BEST) files for single trial & multivariate pattern analyses.';
            app.Label_2 = uilabel(app.BinSelectorUIFigure);
            app.Label_2.Text = 'Click to create and save bin-epoched single-trial (BEST) data file.';
            app.OptionalFrequencyTransformPanel = uipanel(app.BinSelectorUIFigure);
            app.OptionalFrequencyTransformPanel.Title = 'Optional: Frequency Transform';
            app.Label_3 = uilabel(app.OptionalFrequencyTransformPanel);
            app.Label_3.Text = 'Transform trials into phase-independent frequency-band EEG power ';
            app.TFCheckBox = uicheckbox(app.OptionalFrequencyTransformPanel);
            app.TFCheckBox.ValueChangedFcn = createCallbackFcn(app, @TFCheckBoxValueChanged, true);
            app.TFCheckBox.Text = '';
            app.Label_4 = uilabel(app.OptionalFrequencyTransformPanel);
            app.Label_4.Text = '*If resampling is selected, it is performed after frequency-band transform.';
            app.LowEdgeFrequencyinPassBandEditFieldLabel = uilabel(app.OptionalFrequencyTransformPanel);
            app.LowEdgeFrequencyinPassBandEditFieldLabel.Text = 'Low Edge Frequency in Pass Band';
            app.LowEdgeFrequencyinPassBandEditField = uieditfield(app.OptionalFrequencyTransformPanel, 'text');
            app.LowEdgeFrequencyinPassBandEditField.ValueChangedFcn = createCallbackFcn(app, @LowEdgeFrequencyinPassBandEditFieldValueChanged, true);
            app.HighEdgeFrequencyinPassBandEditFieldLabel = uilabel(app.OptionalFrequencyTransformPanel);
            app.HighEdgeFrequencyinPassBandEditFieldLabel.Text = 'High Edge Frequency in Pass Band';
            app.HighEdgeFrequencyinPassBandEditField = uieditfield(app.OptionalFrequencyTransformPanel, 'text');
            app.HighEdgeFrequencyinPassBandEditField.ValueChangedFcn = createCallbackFcn(app, @HighEdgeFrequencyinPassBandEditFieldValueChanged, true);
            app.CurrentSamplingRateHzLabel = uilabel(app.BinSelectorUIFigure);
            app.CurrentSamplingRateHzLabel.Text = 'Current Sampling Rate (Hz)';
            % Show the figure after all components are created
            app.BinSelectorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = binselectorGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BinSelectorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BinSelectorUIFigure)
        end
    end
end