classdef decodingGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UITable                         matlab.ui.control.Table
        SpatialERPDecodingParametersForEachSubjectLabel  matlab.ui.control.Label
        DecodingParametersPanel         matlab.ui.container.Panel
        CrossValidationBlocksEditFieldLabel  matlab.ui.control.Label
        CrossValidationBlocksEditField  matlab.ui.control.NumericEditField
        ChannelsEditFieldLabel          matlab.ui.control.Label
        ChannelsEditField               matlab.ui.control.EditField
        ChanceEditFieldLabel            matlab.ui.control.Label
        ChanceEditField                 matlab.ui.control.NumericEditField
        NumberofLabelsBinsEditFieldLabel  matlab.ui.control.Label
        NumberofLabelsBinsEditField     matlab.ui.control.NumericEditField
        BrowseButton                    matlab.ui.control.Button
        EqualizeTrialsPanel             matlab.ui.container.Panel
        AcrossBinsCheckBox              matlab.ui.control.CheckBox
        AcrossBESTCheckBox              matlab.ui.control.CheckBox
        IterationsEditFieldLabel        matlab.ui.control.Label
        IterationsEditField             matlab.ui.control.NumericEditField
        TimeParametersPanel             matlab.ui.container.Panel
        SelectTimePointstoDecodeLabel   matlab.ui.control.Label
        DecodeeveryNthtimepointmsSpinnerLabel  matlab.ui.control.Label
        TPspinner                       matlab.ui.control.Spinner
        EffectiveSamplingRateHzEditFieldLabel  matlab.ui.control.Label
        EffectiveSamplingRateHzEditField  matlab.ui.control.NumericEditField
        TimePoint0msisalwaysincludedLabel  matlab.ui.control.Label
        DecodingTimeRangeButtonGroup    matlab.ui.container.ButtonGroup
        AllButton                       matlab.ui.control.RadioButton
        CustomButton                    matlab.ui.control.RadioButton
        PreButton                       matlab.ui.control.RadioButton
        PostButton                      matlab.ui.control.RadioButton
        EpochRange                      matlab.ui.control.EditField
        minmaxinmsLabel                 matlab.ui.control.Label
        StartDecodingButton             matlab.ui.control.Button
        ParallelizationButtonGroup      matlab.ui.container.ButtonGroup
        YesButton                       matlab.ui.control.RadioButton
        NoButton                        matlab.ui.control.RadioButton
        ParText                         matlab.ui.control.Label
    end

    
    properties (Access = public)
        ALLBEST % Description
        FinishButton
        ChannelsSelected
        decoding_times_index % Description
        BEST_working % Description
        output % Description
    end
    
    properties (Access = private)
        labeldata % Description
        resample_limit % Description
        epoch_window % Description
    end
    
    methods (Access = private)
        
        function ReIndexTime(app, event, cond)
                %routine everytime Decoding Time Period changes 
                
                %obtain timesample value
                value = app.TPspinner.Value;                    
                %check value if valid from standard
                times = app.ALLBEST(1).times;
                
                % change "EFFECTIVE SAMPLING RATE" window from standard
                BEST = app.ALLBEST(1);
                npnts_old = BEST.pnts;
                fs = BEST.srate;
                samps = 1/fs; %granularity of data sample in ms
                %epochtime = (samps*npnts_old) * 1000 ; %ms

                
                %Make sure resampling includes 0 tp
                %find time zero
                tm_zero = find(times==0);
                value_in_samples = value/(samps*1000);
                
                %start find indexes forward and backward to zero
                
                if strcmpi(cond,'Pre')
                    %tm_zero_to_end = (tm_zero:value_in_samples:length(times));
                    tm_zero_to_begin= (tm_zero:-value_in_samples:1);
                    decoding_times_index = sort([tm_zero_to_begin]);
                    app.decoding_times_index = decoding_times_index; %save to app
                    
                    %change epoch decoding window
                    new_window = [times(decoding_times_index(1)) times(decoding_times_index(end))];
                    edit_time_range = sprintf('%.2f %.2f', new_window(1), new_window(2));
                    
                    %must edit the vars epoch_range uses to make sure user can't change
                    %epoch outside of new resampling limints
                    app.BEST_working.xmin = new_window(1)/1000;
                    app.BEST_working.xmax = new_window(2)/1000;
                    %app.BEST_working.srate = new_FS;
                    app.BEST_working.pnts = length(decoding_times_index);
                    app.BEST_working.times = times(decoding_times_index);
                    
                    %retest values in the epoch range
                    app.EpochRange.Value = edit_time_range;
                    EpochRangeValueChanged(app,event);
                    
                    
                elseif strcmpi(cond,'Post')
                    tm_zero_to_end = (tm_zero:value_in_samples:length(times));
                    %tm_zero_to_begin= (tm_zero-value_in_samples:-value_in_samples:1);
                    decoding_times_index = sort([tm_zero_to_end]);
                    app.decoding_times_index = decoding_times_index; %save to app
                    
                    %change epoch decoding window
                    new_window = [times(decoding_times_index(1)) times(decoding_times_index(end))];
                    edit_time_range = sprintf('%.2f %.2f', new_window(1), new_window(2));
                    
                    %must edit the vars epoch_range uses to make sure user can't change
                    %epoch outside of new resampling limints
                    app.BEST_working.xmin = new_window(1)/1000;
                    app.BEST_working.xmax = new_window(2)/1000;
                    %app.BEST_working.srate = new_FS;
                    app.BEST_working.pnts = length(decoding_times_index);
                    app.BEST_working.times = times(decoding_times_index);
                                        
                    
                    %retest values in the epoch range
                    app.EpochRange.Value = edit_time_range;
                    EpochRangeValueChanged(app,event);
                    
                elseif strcmpi(cond, 'All' )
                    tm_zero_to_end = (tm_zero:value_in_samples:length(times));
                    tm_zero_to_begin= (tm_zero-value_in_samples:-value_in_samples:1);
                    decoding_times_index = sort([tm_zero_to_begin tm_zero_to_end]);
                    app.decoding_times_index = decoding_times_index; %save to app
                    
                    %change epoch decoding window
                    new_window = [times(decoding_times_index(1)) times(decoding_times_index(end))];
                    edit_time_range = sprintf('%.2f %.2f', new_window(1), new_window(2));
                    
                    %must edit the vars epoch_range uses to make sure user can't change
                    %epoch outside of new resampling limints
                    app.BEST_working.xmin = new_window(1)/1000;
                    app.BEST_working.xmax = new_window(2)/1000;
                    %app.BEST_working.srate = new_FS;
                    app.BEST_working.pnts = length(decoding_times_index);
                    app.BEST_working.times = times(decoding_times_index);
                    
                    app.EpochRange.Value = edit_time_range;
                    %retest values in the epoch range
                    EpochRangeValueChanged(app,event);
                    
                    
                elseif strcmpi(cond,'Custom')
                    % must reset the available time as if original values
                    % existed 
                    tm_zero_to_end = (tm_zero:value_in_samples:length(times));
                    tm_zero_to_begin= (tm_zero-value_in_samples:-value_in_samples:1);
                    decoding_times_index = sort([tm_zero_to_begin tm_zero_to_end]);
                    app.decoding_times_index = decoding_times_index; %save to app
                    
                    new_window = [times(decoding_times_index(1)) times(decoding_times_index(end))];
                    
                    %for custom, must reset to original values/'all'
                    app.BEST_working.xmin = new_window(1)/1000;
                    app.BEST_working.xmax = new_window(2)/1000;
                    %app.BEST_working.srate = new_FS;
                    app.BEST_working.pnts = length(decoding_times_index);
                    app.BEST_working.times = times(decoding_times_index);
                    
                    %refresh epoch time
                    app.EpochRange.Value = 'Input Custom Range'; 
                    
                
                end
 
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            %inputs 
            filename = (varargin{1}'); %filename 
            filepath = varargin{2}; %current Set
            ALLBEST = varargin{3}; 
            app.ALLBEST = ALLBEST; % load into public var
            BEST1 = ALLBEST(1); %choose first file as standard
            app.BEST_working = ALLBEST(1); %create working BEST file 
            
            if numel(ALLBEST) == 1
                %must convert to string if only 1 BEST file
                filename = convertCharsToStrings(filename); 
            end


            %Fill parameters
            nbin = BEST1.nbin; %the same across all files 
            app.NumberofLabelsBinsEditField.Value = nbin; 
            app.ChanceEditField.Value = 1/nbin; 
            nBlock = 3; %default
            app.CrossValidationBlocksEditField.Value = nBlock; %default 3
            iter = 100; %default
            app.IterationsEditField.Value = iter; %default 100

            
            %channel array
            chanArray = 1:BEST1.nbchan;
            app.ChannelsSelected = chanArray; % if nothing selected
            app.ChannelsEditField.Value = vect2colon(chanArray,'Delimiter','off');
            
            %time range button group
%             tscale(1) = BEST1.xmin * 1000; %change from s to ms
%             tscale(2) = BEST1.xmax * 1000; %change from s to ms
            tscale(1) = BEST1.times(1); 
            tscale(2) = BEST1.times(end); 
            set(app.AllButton,'Value',1); 
            edit_time_range = sprintf('%.2f %.2f', tscale(1), tscale(2));
            app.EpochRange.Value = edit_time_range;
            app.epoch_window = [tscale(1) tscale(2)];
            set(app.EpochRange, 'Editable','off');
            set(app.EpochRange, 'BackgroundColor',[0.65 0.65 0.65]);      
            %app.EpochStartEditField.Value = BEST(1).xmin; %default
            %app.EpochEndEditField.Value = BEST(1).xmax; %default 
            %app.TimepointsofInterestEditField.Value = BEST(1).times; %default 
            
            %Resampling/Choosing timepoints to decode 
            app.decoding_times_index = 1:(BEST1.pnts); 
            FS = BEST1.srate; 
            %time_sample = (1/FS); %in ms
           % time_sample_r = round(time_sample,3);
            time_sample_zero = find(BEST1.times == 0);
            time_sample = BEST1.times(time_sample_zero + 1); 
            resample_limit = [time_sample abs(BEST1.times(1))+BEST1.times(end)]; %in ms
             %time_limit is not epoch limits; its limit of available re-sampling steps           
             
            set(app.EffectiveSamplingRateHzEditField,'Value', FS);
            set(app.EffectiveSamplingRateHzEditField,'Editable','off');
            set(app.EffectiveSamplingRateHzEditField,'Enable','on');
            set(app.TPspinner,'Value',time_sample);
            set(app.TPspinner,'Editable','on');
            set(app.TPspinner,'Enable','on');
            set(app.TPspinner,'Limits', resample_limit)
            set(app.TPspinner,'Step',resample_limit(1)); 
            app.resample_limit = resample_limit; 
 
            
            %Labels & Number Trials Per Bin (per file)
            labeldata = {};
            rowInd = 0;    
            rowcolors = zeros(1, numel(ALLBEST)*nbin); 
            S1 = uistyle('BackgroundColor', 'white');
            S2 = uistyle('BackgroundColor', [0.8 0.8 0.8]); %light grey
            S3 = uistyle;
            S3.HorizontalAlignment = 'left'; 
            
            for f = 1:numel(ALLBEST)
                
                if f == 1
                    rowcolor  = 0;
                    colswitch = 0; 
                end
                
                for b = 1:nbin
                    
                    rowInd = rowInd +1;
                    
                    labeldata(rowInd,1) = {filename{f}};
                    labeldata(rowInd,2) = ALLBEST(f).bindesc(b);
                    labeldata(rowInd,3) = num2cell(ALLBEST(f).n_trials_per_bin(b));
                     % N_trial_per_bin_per_bock analysis
                    nPerBinBlock = floor(ALLBEST(f).n_trials_per_bin/nBlock); %subject's n_trial_per_bin
                    labeldata(rowInd,4) = num2cell(nPerBinBlock(b)); 
                    
                    %hightlight per file
                    app.UITable.Data = labeldata;

                    
                    if rowcolor == 0 
                        addStyle(app.UITable,S1,'row', rowInd); 
                    else
                        addStyle(app.UITable,S2,'row', rowInd);
                    end
                    
                    if b == nbin
                        if colswitch == 0 
                            rowcolor = 1; 
                            colswitch = 1;
                        else
                            rowcolor = 0; 
                            colswitch = 0;
                        end
                            
                    end
                    
                    
                end


            end
            
            % styling gui: left align "trials" columns
            addStyle(app.UITable,S3,'column',[3 4]);
            %save in app
            app.labeldata = labeldata;
 
   
            %to close out gui
            ready = 0;
            if ready == 1
                
                app.FinishButton = 1; 
            end
                        
            %disp('done')
            
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            
           
            BEST1 = app.ALLBEST(1);
            nchan = BEST1(1).nbchan;
            
            listch = {BEST1(1).chanlocs.labels}; %true channels list as labels
            indxlistch = 1:nchan;  %true array of channel indexs
            indxlistch = indxlistch(indxlistch<=length(listch)); % true array of channel index if less than expected labels
            titlename = 'Select Channel(s)';


%             if get(hObject, 'Value')
            if ~isempty(listch)
                ch = browsechanbinGUI(listch, indxlistch, titlename);
                if ~isempty(ch)
                    %set(handles.chwindow, 'String', vect2colon(ch, 'Delimiter', 'off'));
                    app.ChannelsEditField.Value = vect2colon(ch, 'Delimiter','off'); 
                    %handles.indxlistch = ch;
                    % Update app structure
                    app.ChannelsSelected = ch; 
%                     guidata(hObject, handles);
%                     redraw_outliers(hObject, eventdata, handles);
%                     guidata(hObject, handles); 
                    
                else
                    disp('User selected Cancel')
                    return
                end
            else
                msgboxText =  'No channel information was found';
                title = 'ERPLAB: BrowseChans GUI input';
                errorfound(msgboxText, title);
                return
            end
%                 
%             end


        end

        % Value changed function: EpochRange
        function EpochRangeValueChanged(app, event)
            value = app.EpochRange.Value;
            kktime = 1000; %change from s to ms 
            
            %obtain sample rate from working BEST
            BEST1 = app.BEST_working;
            orig_times = app.ALLBEST(1).times; 
            fs = BEST1.srate;
            
            xxlim = str2num(value);
            %xxlim = app.epoch_window;
            
            if isempty(xxlim) | length(xxlim) <2
                msgboxText =  'You have not specified a time range';
                title = 'ERPLAB: Decoding GUI input';
                errorfound(msgboxText, title);
%                 set(handles.radiobutton_yauto, 'Value',0)
%                 drawnow
                return
            end


            if xxlim(1) < round(app.epoch_window(1),2)
                msgboxText = 'You have input an invalid start time; default to earliest start time'
                title = 'ERPLAB: Decoding GUI input';
                errorfound(msgboxText, title);
                           
                % Revert to default time range
                tscale(1) = app.epoch_window(1); %change from s to ms
                %tscale(2) = BEST1.xmax * 1000; %change from s to ms
                edit_time_range = sprintf('%.2f %.2f', tscale(1), xxlim(2));
                app.EpochRange.Value = edit_time_range;
                %                 set(handles.radiobutton_yauto, 'Value',0)
                %  
                aux_xxlim(1) = tscale(1); 

                %aux_xxlim(1) = round(BEST1.xmin*kktime);
            elseif ~ismember(xxlim(1), round(orig_times,2))
                %check that start time is actually within the new
                %resampling period 
                msgboxTest = sprintf('Invalid start time: Not a valid time-sample. Will round to nearest time-sample!');
                title = 'ERPLAB: wrong time-units';
                errorfound(msgboxTest, title);
                
                %find closest value rounded down
                [~,ind] = min(abs(orig_times-xxlim(1)));
                value = orig_times(ind);
%                 set(app.TPspinner,'Value',value);
%                 app.TPspinner.Value = value;
                tscale(1) = value ; 
                tscale(2) = xxlim(2); %change from s to ms
                edit_time_range = sprintf('%.2f %.2f', tscale(1), tscale(2));
                app.EpochRange.Value = edit_time_range;
                app.epoch_window = [tscale(1) tscale(2)]; 
                aux_xxlim(1) = value; 


            else
                aux_xxlim(1) = xxlim(1);
            end
            
            if xxlim(2)> round(app.epoch_window(end),2)
                msgboxText = 'You have input an invalid end time; default to lateset end time'
                title = 'ERPLAB: Decoding GUI input';
                errorfound(msgboxText, title);
               % Revert to default time range
                %tscale(1) = BEST1(1).xmin * 1000; %change from s to ms
                tscale(2) = app.epoch_window(end); %change from s to ms
                edit_time_range = sprintf('%.2f %.2f', aux_xxlim(1), tscale(2));
                app.EpochRange.Value = edit_time_range;
                
                aux_xxlim(2)=  tscale(2); 

            elseif ~ismember(xxlim(2), round(orig_times,2))
                %check that start time is actually within the new
                %resampling period
                msgboxTest = sprintf('Invalid end time: Not a valid time-sample. Will round!');
                title = 'ERPLAB: wrong time-units';
                errorfound(msgboxTest, title);
                
                %find closest value rounded down
                [~,ind] = min(abs(orig_times-xxlim(2)));
                value = orig_times(ind);
                tscale(1) = aux_xxlim(1) ; 
                tscale(2) = value ; %change from s to ms
                edit_time_range = sprintf('%.2f %.2f', tscale(1), tscale(2));
                app.EpochRange.Value = edit_time_range;
                app.epoch_window = [tscale(1) tscale(2)]; 
                aux_xxlim(2) = value;
                
            else
                aux_xxlim(2) = xxlim(2);
            end
            
            
            [xp1, xp2, checkw] = window2sample(BEST1, aux_xxlim(1:2) , fs, 'relaxed');

            if checkw==1
                msgboxText =  'Time window cannot be larger than epoch.';
                title = 'ERPLAB';
                errorfound(msgboxText, title);
                %set(handles.radiobutton_yauto, 'Value',0)
                %drawnow
                return
            elseif checkw==2
                msgboxText =  'Too narrow time window';
                title = 'ERPLAB';
                errorfound(msgboxText, title);
                %set(handles.radiobutton_yauto, 'Value',0)
                %drawnow
                return
            end
            
            %apply reindexing of timepoints if custom window
            if app.CustomButton.Value == 1
                %obtain timesample value
                value = app.TPspinner.Value;                    
   
                % obtain original sampling rate
                orig_fs = app.ALLBEST(1).srate;
                orig_samps = 1/orig_fs; %granularity of data sample in ms
              
                %Make sure resampling includes 0 tp
                %find time zero
                tm_zero = find(orig_times==0);
                value_in_samples = floor(value/(orig_samps*1000));
                
                %start find indexes forward and backward to timepoints
                tm_end = find(orig_times == aux_xxlim(2));
                tm_begin = find(orig_times == aux_xxlim(1));
                tm_zero_to_end = (tm_zero:value_in_samples:tm_end);
                tm_zero_to_begin= (tm_zero-value_in_samples:-value_in_samples:tm_begin);
                decoding_times_index = sort([tm_zero_to_begin tm_zero_to_end]);
                app.decoding_times_index = decoding_times_index; %save to app
                
                
                %change epoch decoding window
                new_window = [orig_times(decoding_times_index(1)) orig_times(decoding_times_index(end))];
                edit_time_range = sprintf('%.2f %.2f', new_window(1), new_window(2));
                app.EpochRange.Value = edit_time_range;
                app.epoch_window = new_window; 
                %             set(app.AllButton,'Value',1);
                %             EpochRangeValueChanged(app,event);
                
                %must edit the vars epoch_range uses to make sure user can't change
                %epoch outside of new resampling limints
                app.BEST_working.xmin = new_window(1)/1000;
                app.BEST_working.xmax = new_window(2)/1000;
                %app.BEST_working.srate = new_FS;
                app.BEST_working.pnts = length(decoding_times_index);
                app.BEST_working.times = orig_times(decoding_times_index);             
                %set(app.EpochRange,'Value',edit_time_range); 
               % print('hi');
                
            end


        end

        % Value changed function: AcrossBinsCheckBox
        function AcrossBinsCheckBoxValueChanged(app, event)
            value = app.AcrossBinsCheckBox.Value;
            
            ALLBEST = app.ALLBEST;
            nbins = ALLBEST.nbin;
            nsubs = numel(ALLBEST); 
            
%             
            if value == 1
                %equalize across bins within files 

                nPerBin = [app.labeldata{:,3}]; %obtain original values
                nBlocks = app.CrossValidationBlocksEditField.Value; 
                
                for s = 1:nsubs
                   
                    
                    subj_ntrials = ALLBEST(s).n_trials_per_bin; %all trials per subject
                    
                    minCnt = min(subj_ntrials);
                    % max # of trials such that # of trials for all bins ...
                    % can be equated within each block
                    nPerBinBlock(s) = floor(minCnt/nBlocks);
                end
                
                %apply nPerBinBlock to nPerBin values 
                indx = 0 ; 
                for s = 1:nsubs
                    for b = 1:nbins
                        indx = indx +1; 
                        nPerBin(indx) = nPerBinBlock(s); 
                        
                    end
                end

            
%                 minCnt = min(nPerBin);
%                 nBlocks = app.CrossValidationBlocksEditField.Value; 
%                 % max # of trials such that # of trials for all bins ...
%                 % can be equated within each block 
%                 nPerBinBlock = floor(minCnt/nBlocks); 

                app.labeldata(:,4) = num2cell(nPerBin);
                %redraw table
                app.UITable.Data = app.labeldata;
            else
                %redo original N(per ERP) column
                %BEST = app.BEST;
                
                if app.AcrossBESTCheckBox.Value == 1
                    %don't do anything
                    return
                end
                
                nbin = ALLBEST(1).nbin; %the same across all files
                nBlock = app.CrossValidationBlocksEditField.Value; 
                rowInd = 0;
                for f = 1:numel(ALLBEST)

                    for b = 1:nbin
                        
                        rowInd = rowInd +1;
                        
                        % N_trial_per_bin_per_bock analysis
                        nPerBinBlock = floor(ALLBEST(f).n_trials_per_bin/nBlock); %subject's n_trial_per_bin
                        app.labeldata(rowInd,4) = num2cell(nPerBinBlock(b));              
                             
                    end

                end
                
                %redraw table
                app.UITable.Data = app.labeldata;
                
            end
            
            %flag = 1; 
            
        end

        % Value changed function: CrossValidationBlocksEditField
        function CrossValidationBlocksEditFieldValueChanged(app, event)
            value = app.CrossValidationBlocksEditField.Value;
            
            %must change the N(per ERP) 
            AcrossBinsCheckBoxValueChanged(app,event);

        end

        % Value changed function: TPspinner
        function TPspinnerValueChanged(app, event)
            value = app.TPspinner.Value;
            
            
            %check value if valid from standard
            times = app.ALLBEST(1).times;
            resample_limit = app.resample_limit;
            
            if ~ismember(value,times)
                msgboxTest = sprintf('Selecting time-points: must be in units of %i ms. Will round!',resample_limit(1));
                title = 'ERPLAB: wrong time-units';
                errorfound(msgboxTest, title);
                
                %find closest value rounded down
                [~,ind] = min(abs(times-value));
                value = times(ind);
                set(app.TPspinner,'Value',value);
                app.TPspinner.Value = value;
            end
            
            % change "EFFECTIVE SAMPLING RATE" window from standard
            BEST = app.ALLBEST(1);             
            npnts_old = BEST.pnts; 
            fs = BEST.srate; 
            samps = 1/fs; %granularity of data sample in ms 
            epochtime = (samps*npnts_old) * 1000 ; %ms           
            npnts = round(epochtime/value); %can't have intermediate points         
            new_FS = (npnts/epochtime * 1000);
            
            %save changes
            set(app.EffectiveSamplingRateHzEditField, 'Value', new_FS); 
            
            %Make sure resampling includes 0 tp
            %find time zero 
            tm_zero = find(times==0); 
            value_in_samples = value/(samps*1000);
            
            %start find indexes forward and backward to zero 
            tm_zero_to_end = (tm_zero:value_in_samples:length(times));
            tm_zero_to_begin= (tm_zero-value_in_samples:-value_in_samples:1); 
            decoding_times_index = sort([tm_zero_to_begin tm_zero_to_end]);
            
            app.decoding_times_index = decoding_times_index; %save to app
            
            %change epoch decoding window 
            new_window = [times(decoding_times_index(1)) times(decoding_times_index(end))];
            edit_time_range = sprintf('%.2f %.2f', new_window(1), new_window(2));
            app.EpochRange.Value = edit_time_range;
            app.epoch_window = new_window; 
%             set(app.AllButton,'Value',1); 
%             EpochRangeValueChanged(app,event); 
            
            %must edit the vars epoch_range uses to make sure user can't change
            %epoch outside of new resampling limints
            app.BEST_working.xmin = new_window(1)/1000; 
            app.BEST_working.xmax = new_window(2)/1000;
            app.BEST_working.srate = new_FS;
            app.BEST_working.pnts = length(decoding_times_index);
            app.BEST_working.times = times(decoding_times_index); 
            
            %update the epoch window 
            set(app.AllButton,'Value',1);
            EpochRangeValueChanged(app,event);

            
        end

        % Button pushed function: StartDecodingButton
        function StartDecodingButtonPushed(app, event)
            
            %Query all outputs
            nBins = app.BEST_working.nbin; 
            nIter = app.IterationsEditField.Value; 
            nCrossBlocks = app.CrossValidationBlocksEditField.Value;
            DataTimes.pre = app.BEST_working.xmin; 
            DataTimes.post = app.BEST_working.xmax; 
            times = app.BEST_working.times; 
            decoding_times = app.decoding_times_index;
            relevantChans = app.ChannelsSelected;          
            nPerBinBlock = app.UITable.Data(:,4);
            ParCompute = app.YesButton.Value; 
            
            %output in cell
            outputs = {nBins, nIter, nCrossBlocks, DataTimes, times, decoding_times, relevantChans, nPerBinBlock,ParCompute}; 
            
            app.output = outputs ; 
            app.FinishButton = 1; 


        end

        % Selection changed function: DecodingTimeRangeButtonGroup
        function DecodingTimeRangeButtonGroupSelectionChanged(app, event)
            selectedButton = app.DecodingTimeRangeButtonGroup.SelectedObject; %string
            
            selected_range = selectedButton.Text; 
            %epoch_range = app.EpochRange.Value; %ms 
            %BEST1 = app.BEST_working;


        switch selected_range
            case 'All'
                %use full range xmin to xmax
%                 tscale(1) = BEST1(1).xmin * 1000; %change from s to ms
%                 tscale(2) = BEST1(1).xmax * 1000; %change from s to ms
%                 edit_time_range = sprintf('%.2f %.2f', tscale(1), tscale(2));
%                 app.EpochRange.Value = edit_time_range;
%                 EpochRangeValueChanged(app, event);
                ReIndexTime(app, event, 'All')
                set(app.EpochRange,'BackgroundColor',[0.65 0.65 0.65]); 
                set(app.EpochRange,'Editable','off'); 
                
            case 'Custom'
                ReIndexTime(app, event, 'Custom')
                set(app.EpochRange,'Editable','on');     
                set(app.EpochRange,'BackgroundColor',[1 1 1]); 
                
            case 'Pre'
                %use range xmin to 0
                ReIndexTime(app, event, 'Pre')
           
                set(app.EpochRange,'Editable','off');     
                set(app.EpochRange,'BackgroundColor',[0.65 0.65 0.65]); 
                
            case 'Post'
                ReIndexTime(app, event, 'Post')

                set(app.EpochRange,'Editable','off');     
                set(app.EpochRange,'BackgroundColor',[0.65 0.65 0.65]); 
                
        end
            
          % switch selectedButton.text; 
            
            %if selectedButton
            
        end

        % Value changed function: AcrossBESTCheckBox
        function AcrossBESTCheckBoxValueChanged(app, event)
            value = app.AcrossBESTCheckBox.Value;
            
            
            ALLBEST = app.ALLBEST;
            nbins = ALLBEST.nbin;
            nsubs = numel(ALLBEST); 
            
%             
            if value == 1
                %equalize across bins across files 

                nPerBin = [app.labeldata{:,3}]; %obtain original values
                nBlocks = app.CrossValidationBlocksEditField.Value; 
                
                for s = 1:nsubs
                   
                    
                    subj_ntrials = ALLBEST(s).n_trials_per_bin; %all trials per subject
                    
                    minCnt = min(subj_ntrials);
                    % max # of trials such that # of trials for all bins ...
                    % can be equated within each block
                    nPerBinBlock(s) = floor(minCnt/nBlocks);
                end
                
                %apply nPerBinBlock to nPerBin values 
                indx = 0 ; 
                for s = 1:nsubs
                    for b = 1:nbins
                        indx = indx +1; 
                        nPerBin(indx) = min(nPerBinBlock); 
                        
                    end
                end

            
%                 minCnt = min(nPerBin);
%                 nBlocks = app.CrossValidationBlocksEditField.Value; 
%                 % max # of trials such that # of trials for all bins ...
%                 % can be equated within each block 
%                 nPerBinBlock = floor(minCnt/nBlocks); 

                app.labeldata(:,4) = num2cell(nPerBin);
                %redraw table
                app.UITable.Data = app.labeldata;
            else
                
                if app.AcrossBinsCheckBox.Value == 1
                    AcrossBinsCheckBoxValueChanged(app,event);
                    %return
                else
                    
                    %redo original N(per ERP) column
                    %BEST = app.BEST;
                    nbin = ALLBEST(1).nbin; %the same across all files
                    nBlock = app.CrossValidationBlocksEditField.Value;
                    rowInd = 0;
                    for f = 1:numel(ALLBEST)
                        
                        for b = 1:nbin
                            
                            rowInd = rowInd +1;
                            
                            % N_trial_per_bin_per_bock analysis
                            nPerBinBlock = floor(ALLBEST(f).n_trials_per_bin/nBlock); %subject's n_trial_per_bin
                            app.labeldata(rowInd,4) = num2cell(nPerBinBlock(b));
                            
                        end
                        
                    end
                    
                    %redraw table
                    app.UITable.Data = app.labeldata;
                end
                
            end
            
        end

        % Value changed function: IterationsEditField
        function IterationsEditFieldValueChanged(app, event)
            value = app.IterationsEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'BEST File'; 'Bin/Label'; 'N(trials)'; 'N(per ERP)'};
            app.UITable.RowName = {};
            app.SpatialERPDecodingParametersForEachSubjectLabel = uilabel(app.UIFigure);
            app.SpatialERPDecodingParametersForEachSubjectLabel.Text = 'Spatial ERP Decoding Parameters For Each Subject';
            app.DecodingParametersPanel = uipanel(app.UIFigure);
            app.DecodingParametersPanel.Title = 'Decoding Parameters';
            app.CrossValidationBlocksEditFieldLabel = uilabel(app.DecodingParametersPanel);
            app.CrossValidationBlocksEditFieldLabel.Text = 'Cross-Validation Blocks';
            app.CrossValidationBlocksEditField = uieditfield(app.DecodingParametersPanel, 'numeric');
            app.CrossValidationBlocksEditField.ValueChangedFcn = createCallbackFcn(app, @CrossValidationBlocksEditFieldValueChanged, true);
            app.ChannelsEditFieldLabel = uilabel(app.DecodingParametersPanel);
            app.ChannelsEditFieldLabel.Text = 'Channels';
            app.ChannelsEditField = uieditfield(app.DecodingParametersPanel, 'text');
            app.ChanceEditFieldLabel = uilabel(app.DecodingParametersPanel);
            app.ChanceEditFieldLabel.Text = 'Chance';
            app.ChanceEditField = uieditfield(app.DecodingParametersPanel, 'numeric');
            app.ChanceEditField.Editable = 'off';
            app.NumberofLabelsBinsEditFieldLabel = uilabel(app.DecodingParametersPanel);
            app.NumberofLabelsBinsEditFieldLabel.Text = 'Number of Labels/Bins';
            app.NumberofLabelsBinsEditField = uieditfield(app.DecodingParametersPanel, 'numeric');
            app.NumberofLabelsBinsEditField.Editable = 'off';
            app.BrowseButton = uibutton(app.DecodingParametersPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.EqualizeTrialsPanel = uipanel(app.DecodingParametersPanel);
            app.EqualizeTrialsPanel.Title = 'Equalize Trials ...';
            app.AcrossBinsCheckBox = uicheckbox(app.EqualizeTrialsPanel);
            app.AcrossBinsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AcrossBinsCheckBoxValueChanged, true);
            app.AcrossBinsCheckBox.Text = 'Across Bins ';
            app.AcrossBESTCheckBox = uicheckbox(app.EqualizeTrialsPanel);
            app.AcrossBESTCheckBox.ValueChangedFcn = createCallbackFcn(app, @AcrossBESTCheckBoxValueChanged, true);
            app.AcrossBESTCheckBox.Text = 'Across BEST';
            app.IterationsEditFieldLabel = uilabel(app.DecodingParametersPanel);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.DecodingParametersPanel, 'numeric');
            app.IterationsEditField.ValueChangedFcn = createCallbackFcn(app, @IterationsEditFieldValueChanged, true);
            app.TimeParametersPanel = uipanel(app.UIFigure);
            app.TimeParametersPanel.Title = 'Time Parameters ';
            app.SelectTimePointstoDecodeLabel = uilabel(app.TimeParametersPanel);
            app.SelectTimePointstoDecodeLabel.Text = 'Select Time-Points to Decode*';
            app.DecodeeveryNthtimepointmsSpinnerLabel = uilabel(app.TimeParametersPanel);
            app.DecodeeveryNthtimepointmsSpinnerLabel.Text = 'Decode every Nth time-point (ms)';
            app.TPspinner = uispinner(app.TimeParametersPanel);
            app.TPspinner.ValueChangedFcn = createCallbackFcn(app, @TPspinnerValueChanged, true);
            app.EffectiveSamplingRateHzEditFieldLabel = uilabel(app.TimeParametersPanel);
            app.EffectiveSamplingRateHzEditFieldLabel.Text = 'Effective Sampling Rate (Hz)';
            app.EffectiveSamplingRateHzEditField = uieditfield(app.TimeParametersPanel, 'numeric');
            app.TimePoint0msisalwaysincludedLabel = uilabel(app.TimeParametersPanel);
            app.TimePoint0msisalwaysincludedLabel.Text = '*Time-Point 0 (ms) is always included ';
            app.DecodingTimeRangeButtonGroup = uibuttongroup(app.TimeParametersPanel);
            app.DecodingTimeRangeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DecodingTimeRangeButtonGroupSelectionChanged, true);
            app.DecodingTimeRangeButtonGroup.Title = 'Decoding Time Range';
            app.AllButton = uiradiobutton(app.DecodingTimeRangeButtonGroup);
            app.AllButton.Text = 'All';
            app.AllButton.Value = true;
            app.CustomButton = uiradiobutton(app.DecodingTimeRangeButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.PreButton = uiradiobutton(app.DecodingTimeRangeButtonGroup);
            app.PreButton.Text = 'Pre';
            app.PostButton = uiradiobutton(app.DecodingTimeRangeButtonGroup);
            app.PostButton.Text = 'Post';
            app.EpochRange = uieditfield(app.DecodingTimeRangeButtonGroup, 'text');
            app.EpochRange.ValueChangedFcn = createCallbackFcn(app, @EpochRangeValueChanged, true);
            app.minmaxinmsLabel = uilabel(app.DecodingTimeRangeButtonGroup);
            app.minmaxinmsLabel.Text = '(min, max in ms)';
            app.StartDecodingButton = uibutton(app.UIFigure, 'push');
            app.StartDecodingButton.ButtonPushedFcn = createCallbackFcn(app, @StartDecodingButtonPushed, true);
            app.StartDecodingButton.Text = 'Start Decoding';
            app.ParallelizationButtonGroup = uibuttongroup(app.UIFigure);
            app.ParallelizationButtonGroup.Title = 'Parallelization';
            app.YesButton = uiradiobutton(app.ParallelizationButtonGroup);
            app.YesButton.Text = 'Yes';
            app.YesButton.Value = true;
            app.NoButton = uiradiobutton(app.ParallelizationButtonGroup);
            app.NoButton.Text = 'No';
            app.ParText = uilabel(app.ParallelizationButtonGroup);
            app.ParText.Text = '*If yes, ERPLAB will attempt to speed up decoding process across CPU cores';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = decodingGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end