classdef mvpc2textGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SaveOutputasEditFieldLabel_2  matlab.ui.control.Label
        ExportButton                  matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        TransposeDataButtonGroup      matlab.ui.container.ButtonGroup
        rowspoints                    matlab.ui.control.RadioButton
        rowsvalue                     matlab.ui.control.RadioButton
        TimePanel                     matlab.ui.container.Panel
        TimeunitsDropDown             matlab.ui.control.DropDown
        TimeunitsDropDownLabel        matlab.ui.control.Label
        IncludetimevaluesCheckBox     matlab.ui.control.CheckBox
        Panel                         matlab.ui.container.Panel
        MVPCvaluetoexportEditField    matlab.ui.control.EditField
        MVPCvaluetoexportEditFieldLabel  matlab.ui.control.Label
        BrowseButton                  matlab.ui.control.Button
        SaveOutputasEditField         matlab.ui.control.EditField
        SaveOutputasEditFieldLabel    matlab.ui.control.Label
    end

    
    properties (Access = public)
        FinishButton % Description
        output % Description
    end
    
    properties (Access = private)
       def
       MVPC % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)

            %inputs
            MVPC = varargin{1}; 
            %filename = (varargin{2}'); %filename 
            %filepath = varargin{3}; % list of paths
            def = varargin{2};
            
            %save defaults and currently loaded bestset index
            app.def = def;
            app.MVPC = MVPC; %from GUI input
            

            istime = def{1};
            tunits = def{2}; 
            trdata = def{3};
           % prec = def{4};
            fname = def{4}; 
            try suffixflag = varargin{3}; catch suffixflag=0;  end
            if suffixflag==0
               app.SaveOutputasEditFieldLabel_2.Text=''; 
            else
               app.SaveOutputasEditFieldLabel_2.Text= 'File name will be added to MVPC dataset name';
            end
            
             %apply defaults 
            set(app.SaveOutputasEditField,'Value',fname);%outputas
            
            set(app.MVPCvaluetoexportEditField,'Value',MVPC.DecodingUnit); % not editable
            set(app.MVPCvaluetoexportEditField,'Editable','off'); 
            app.TimeunitsDropDown.Items = {'milliseconds','seconds'}; 
            
            if istime == 1 %includetime
                
                set(app.IncludetimevaluesCheckBox,'Value',1);
                set(app.TimeunitsDropDown,'Enable','on');
                if tunits == 1E-3
                    set(app.TimeunitsDropDown,'Value','milliseconds')
                else
                    set(app.TimeunitsDropDown,'Value','seconds')
                end
                
                
            else
                set(app.IncludetimevaluesCheckBox,'Value',0);
                set(app.TimeunitsDropDown,'Enable','off');
            end


            if trdata == 1
                set(app.rowspoints,'Value',1);
            else
                set(app.rowsvalue,'Value',1);
            end
            
            %paint GUI
            painterplabapp(app);
            
                        %
            % Name & version
            %
            version = geterplabversion;
            set(app.UIFigure,'Name', ['ERPLAB ' version '   -   EXPORT MVPC GUI']);


            %to close out gui
            ready = 0;
            if ready == 1
                app.FinishButton = 1;
            end


        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            namef = app.SaveOutputasEditField.Value; 
            
            [pathstr, namedef, ext] = fileparts(char(namef));
            [textfilename, textpathname, indxs] = uiputfile({'*.txt','(*.txt)'}, ...
                'Save as',...
                namedef);
            
            if isequal(textfilename,0)
                disp('User selected Cancel')
               % serror = 1;
                return
            end
            
            [pathstr, textfilename, ext] = fileparts(textfilename) ;           
            ext = '.txt'; 
            
            filenamex = fullfile(textpathname, [textfilename ext]); 
            
            set(app.SaveOutputasEditField,'Value',filenamex);
            
            
        end

        % Value changed function: IncludetimevaluesCheckBox
        function IncludetimevaluesCheckBoxValueChanged(app, event)
            value = app.IncludetimevaluesCheckBox.Value;
            
            if value == 1
                set(app.TimeunitsDropDown,'Enable','on')
                
            else
                set(app.TimeunitsDropDown,'Enable','off')
                
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            
            %generate output
                
            fname = app.SaveOutputasEditField.Value; 
            
            
            istime =  app.IncludetimevaluesCheckBox.Value;
            if istime == 1        
                tval = app.TimeunitsDropDown;
                switch  tval.Value
                    case 'milliseconds'
                        choiceT = 1E-3;
                    case 'seconds'
                        choiceT = 1;    
                end
            else
               choiceT = 'milliseconds'; 
            end
            
            if app.rowspoints.Value ==1 
                trdata = 1 ;
            else
                trdata = 0; 
               
            end
            
            
            app.output = {istime, choiceT, trdata, fname}; 
            app.FinishButton = 1; 


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SaveOutputasEditFieldLabel = uilabel(app.UIFigure);
            app.SaveOutputasEditFieldLabel.Text = 'Save Output as';
            app.SaveOutputasEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.MVPCvaluetoexportEditFieldLabel = uilabel(app.UIFigure);
            app.MVPCvaluetoexportEditFieldLabel.Text = 'MVPC value to export';
            app.MVPCvaluetoexportEditField = uieditfield(app.UIFigure, 'text');
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.TimePanel = uipanel(app.UIFigure);
            app.TimePanel.Title = 'Time';
            app.IncludetimevaluesCheckBox = uicheckbox(app.TimePanel);
            app.IncludetimevaluesCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludetimevaluesCheckBoxValueChanged, true);
            app.IncludetimevaluesCheckBox.Text = 'Include time values';
            app.TimeunitsDropDownLabel = uilabel(app.TimePanel);
            app.TimeunitsDropDownLabel.Text = 'Time units';
            app.TimeunitsDropDown = uidropdown(app.TimePanel);
            app.TransposeDataButtonGroup = uibuttongroup(app.UIFigure);
            app.TransposeDataButtonGroup.Title = 'Transpose Data ';
            app.rowsvalue = uiradiobutton(app.TransposeDataButtonGroup);
            app.rowsvalue.Text = 'Store values in rows';
            app.rowsvalue.Value = true;
            app.rowspoints = uiradiobutton(app.TransposeDataButtonGroup);
            app.rowspoints.Text = 'Store values in columns';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.SaveOutputasEditFieldLabel_2 = uilabel(app.UIFigure);
            app.SaveOutputasEditFieldLabel_2.Text = 'Save Output as';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mvpc2textGUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end