classdef plotConfusionGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ValuetoplotDropDownLabel  matlab.ui.control.Label
        ValuetoplotDropDown       matlab.ui.control.DropDown
        LatencyiestoplotPanel     matlab.ui.container.Panel
        EpochRange                matlab.ui.control.EditField
        etcLabel                  matlab.ui.control.Label
        PlotextrasPanel           matlab.ui.container.Panel
        ColorPlotDropDownLabel    matlab.ui.control.Label
        ColorPlotDropDown         matlab.ui.control.DropDown
        PlotButton                matlab.ui.control.Button
    end

    
    properties (Access = public)
        FinishButton % Description
        output % Description
    end
    
    properties (Access = private)
        plot_menu % Description
        plot_cmap % Description
        cmaps
        ptype
        MVPC % Description
        plot_epoch % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            
            ALLMVPC = varargin{1}; 
            currdata = varargin{2};
            plot_menu = varargin{3}{1}; 
            clrmap = varargin{3}{2}; 
            
            
            plot_type = {'Timepoint Confusion Matrix', 'Average Confusion Matrix between two latencies'};
            
            app.ValuetoplotDropDown.Items = plot_type;
            app.ValuetoplotDropDown.Value = plot_type{plot_menu}; 
            
            if plot_menu == 1 
               text_instruct = 'e.g., 300 to plot confusion matrix at 300ms or or 100:50:350 to plot at 100, 150, 200,...,350 ms' ;
               app.etcLabel.Text = text_instruct;
               
            elseif plot_menu == 2
                text_instruct = 'e.g., 200 250 to plot average confusion matrix across 200 to 250 ms';
                app.etcLabel.Text = text_instruct;                          
            end
            
            cmaps = {'jet','hsv', 'hot', 'cool', 'gray','viridis'}; 
            app.ColorPlotDropDown.Items = cmaps; 
            app.ColorPlotDropDown.Value = cmaps{clrmap}; 
            
%             switch clrmap
%                 case 1
%                     cmap = 'jet';
%                 case 2
%                     cmap = 'hsv';
%                 case 3
%                     cmap = 'hot';
%                 case 4
%                     cmap = 'cool';
%                 case 5
%                     cmap = 'gray';
%                 case 6
%                     cmap = 'viridis';
%                 otherwise
%                     cmap = 'jet';
%             end


            %save into app
            app.plot_menu = plot_menu;
            app.plot_cmap = clrmap; 
            app.cmaps = cmaps;
            app.ptype = plot_type;
            app.MVPC = ALLMVPC(currdata); 


            %paint GUI
            painterplabapp(app);
               
            %to close out gui
            ready = 0;
            if ready == 1  
                app.FinishButton = 1; 
            end
                       
        end

        % Value changed function: ValuetoplotDropDown
        function ValuetoplotDropDownValueChanged(app, event)
            value = app.ValuetoplotDropDown.Value;
            
            plot_types = app.ptype;
            idx = find(strcmp(plot_types, value));
     
                        
            if idx == 1
               text_instruct = 'e.g., 300 to plot confusion matrix at 300ms or or 100:50:350 to plot at 100, 150, 200,...,350 ms' ;
               app.etcLabel.Text = text_instruct;
               
            elseif idx == 2
                text_instruct = 'e.g., 200 250 to plot average confusion matrix across 200 to 250 ms';
                app.etcLabel.Text = text_instruct;                          
            end
            
            app.plot_menu = idx; 
            
        end

        % Value changed function: EpochRange
        function EpochRangeValueChanged(app, event)
            value = app.EpochRange.Value;
            
            
            kktime = 1000; %change from s to ms 
            
            %reset working bestfile
            MVPC = app.MVPC; 
            orig_times = MVPC.times;
            epoch_window = [orig_times(1) orig_times(end)];
%             app.BEST_working.xmin = MVPC.xmin;
%             app.BEST_working.xmax = MVPC.xmax;
%             app.BEST_working.srate = MVPC.srate;
%             app.BEST_working.pnts = MVPC.pnts;
%             app.BEST_working.times = orig_times;
            fs = MVPC.nSampling; 
            
            xxlim = str2num(value);
            %xxlim = app.epoch_window;
            
            if app.plot_menu ~= 1
                if isempty(xxlim) | length(xxlim) <2
                    msgboxText =  'You have not specified a time range';
                    title = 'ERPLAB: GUI latencies input';
                    errorfound(msgboxText, title);
                    %                 set(handles.radiobutton_yauto, 'Value',0)
                    %                 drawnow
                    return
                end
            end
            
            
            if xxlim(1) < round(epoch_window(1),2)
                msgboxText = 'You have input an invalid start time; default to earliest start time'
                title = 'ERPLAB: GUI input';
                errorfound(msgboxText, title);
                           
                % Revert to default time range
                tscale(1) = epoch_window(1); %change from s to ms
                %tscale(2) = MVPC.xmax * 1000; %change from s to ms
                edit_time_range = sprintf('%.2f %.2f', tscale(1), xxlim(2));
                app.EpochRange.Value = edit_time_range;
                %                 set(handles.radiobutton_yauto, 'Value',0)
                %  
                aux_xxlim(1) = tscale(1); 

                %aux_xxlim(1) = round(MVPC.xmin*kktime);
            elseif ~ismember(xxlim(1), round(orig_times,2))
                %check that start time is actually within the new
                %resampling period 
                msgboxTest = sprintf('Invalid start time: Not a valid time-sample. Will round to nearest time-sample!');
                title = 'ERPLAB: wrong time-units';
                errorfound(msgboxTest, title);
                
                %find closest value
                %[~,ind] = min(abs(orig_times-xxlim(1)));
                %value = orig_times(ind);
                [value,ind] = closest(orig_times,xxlim(1)); 
                
%                 set(app.TPspinner,'Value',value);
%                 app.TPspinner.Value = value;
                tscale(1) = value ; 
                tscale(2) = xxlim(2); %change from s to ms
                edit_time_range = sprintf('%.2f %.2f', tscale(1), tscale(2));
                app.EpochRange.Value = edit_time_range;
                epoch_window = [tscale(1) tscale(2)]; 
                aux_xxlim(1) = value; 


            else
                aux_xxlim(1) = xxlim(1);
            end
            
            if app.plot_menu ~= 1
                if xxlim(2)> round(epoch_window(end),2)
                    msgboxText = 'You have input an invalid end time; default to lateset end time'
                    title = 'ERPLAB: GUI input';
                    errorfound(msgboxText, title);
                    % Revert to default time range
                    %tscale(1) = MVPC(1).xmin * 1000; %change from s to ms
                    tscale(2) = epoch_window(end); %change from s to ms
                    edit_time_range = sprintf('%.2f %.2f', aux_xxlim(1), tscale(2));
                    app.EpochRange.Value = edit_time_range;
                    
                    aux_xxlim(2)=  tscale(2);
                    
                elseif ~ismember(xxlim(2), round(orig_times,2))
                    %check that start time is actually within the new
                    %resampling period
                    msgboxTest = sprintf('Invalid end time: Not a valid time-sample. Will round!');
                    title = 'ERPLAB: wrong time-units';
                    errorfound(msgboxTest, title);
                    
                    %find closest value
                    %[~,ind] = min(abs(orig_times-xxlim(2)));
                    %value = orig_times(ind);
                    [value,ind] = closest(orig_times,xxlim(2));
                    tscale(1) = aux_xxlim(1) ;
                    tscale(2) = value ; %change from s to ms
                    edit_time_range = sprintf('%.2f %.2f', tscale(1), tscale(2));
                    app.EpochRange.Value = edit_time_range;
                    epoch_window = [tscale(1) tscale(2)];
                    aux_xxlim(2) = value;
                    
                else
                    aux_xxlim(2) = xxlim(2);
                end
            end
            
            if app.plot_menu ~= 1
                [xp1, xp2, checkw] = window2sample(MVPC, aux_xxlim(1:2) , fs, 'relaxed');
                
                if checkw==1
                    msgboxText =  'Time window cannot be larger than epoch.';
                    title = 'ERPLAB';
                    errorfound(msgboxText, title);
                    app.EpochRange.Value = 'Input Custom Range';
                    %set(handles.radiobutton_yauto, 'Value',0)
                    %drawnow
                    return
                elseif checkw==2
                    msgboxText =  'Too narrow time window (are the start and end times reversed?)';
                    title = 'ERPLAB';
                    errorfound(msgboxText, title);
                    app.EpochRange.Value = 'Input Custom Range';
                    %set(handles.radiobutton_yauto, 'Value',0)
                    %drawnow
                    return
                end
            end
            
            app.EpochRange.Value = num2str(aux_xxlim);
            app.plot_epoch = aux_xxlim; 
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            %get output
            meas = app.plot_menu;
            epoch_times  = app.plot_epoch;
            color_map = app.plot_cmap;
            
            res = {meas,color_map, epoch_times};
            
            app.output =res;
            app.FinishButton = 1; 
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ValuetoplotDropDownLabel = uilabel(app.UIFigure);
            app.ValuetoplotDropDownLabel.Text = 'Value to plot';
            app.ValuetoplotDropDown = uidropdown(app.UIFigure);
            app.ValuetoplotDropDown.ValueChangedFcn = createCallbackFcn(app, @ValuetoplotDropDownValueChanged, true);
            app.LatencyiestoplotPanel = uipanel(app.UIFigure);
            app.LatencyiestoplotPanel.Title = 'Latency(ies) to plot';
            app.EpochRange = uieditfield(app.LatencyiestoplotPanel, 'text');
            app.EpochRange.ValueChangedFcn = createCallbackFcn(app, @EpochRangeValueChanged, true);
            app.etcLabel = uilabel(app.LatencyiestoplotPanel);
            app.etcLabel.Text = 'etc';
            app.PlotextrasPanel = uipanel(app.UIFigure);
            app.PlotextrasPanel.Title = 'Plot extras';
            app.ColorPlotDropDownLabel = uilabel(app.PlotextrasPanel);
            app.ColorPlotDropDownLabel.Text = 'Color Plot';
            app.ColorPlotDropDown = uidropdown(app.PlotextrasPanel);
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotConfusionGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end