classdef Decode_mvpc_line_legend_ops < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SettingsforlegendPanel   matlab.ui.container.Panel
        columnDropDownLabel      matlab.ui.control.Label
        columnDropDown           matlab.ui.control.DropDown
        TextcolorButtonGroup     matlab.ui.container.ButtonGroup
        SameaslinesButton        matlab.ui.control.RadioButton
        AutoButton_3             matlab.ui.control.RadioButton
        FontsizeDropDown         matlab.ui.control.DropDown
        FontsizeDropDownLabel    matlab.ui.control.Label
        FontDropDown             matlab.ui.control.DropDown
        FontDropDownLabel        matlab.ui.control.Label
        LegendButtonGroup        matlab.ui.container.ButtonGroup
        CustomButton_2           matlab.ui.control.RadioButton
        AutoButton_2             matlab.ui.control.RadioButton
        LineSettingsButtonGroup  matlab.ui.container.ButtonGroup
        CustomButton             matlab.ui.control.RadioButton
        AutoButton               matlab.ui.control.RadioButton
        ApplyButton              matlab.ui.control.Button
        CancelButton             matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
    end


    properties (Access = private)
    end

    properties (Access = public)
        Output % Description
        Finishbutton % Description
        ALLERP % Description
        ERPArray % Description
    end

    methods (Access = private)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            painterplabapp(app);
            setfonterplab(app);
            % Name & version
            %
            version = geterplabversion;
            set(app.UIFigure,'Name', ['Estudio ' version '   -   Settings for lines and legend for MVPCsets GUI']);

            try Lineparas = varargin{1}; catch Lineparas = [];end
            try linAutoValue = Lineparas{1}; catch  linAutoValue=1;end
            try lineset_strinput = Lineparas{2}; catch  lineset_strinput = []; end
            [lineNameStr,linecolors,linetypes,linewidths,linecolors_str,linetypes_str,linewidths_str,linecolorsrgb] = f_get_lineset_ERPviewer();
            if linAutoValue
                lineset_str  =table(lineNameStr,linecolors,linetypes,linewidths);
                lineset_strdef = table2cell(lineset_str);
            else
                lineset_str  =table(lineNameStr,linecolorsrgb,linetypes,linewidths);
                lineset_strdef = table2cell(lineset_str);
            end
            if isempty(lineset_strinput)
                lineset_strinput = lineset_strdef;
            end

            ColumnNames = {'# Line','Color','Style', 'Width'};
            app.UITable.ColumnFormat = {'char', 'char',...
                {'solid','dash','dot','dashdot','plus','circle','asterisk'},...
                {'0.25','0.5','1','1.5','2','2.5','3','3.5','4','4.5','5','5.5','6','6.5','7','7.5','8','8.5','9','9.5','10','10.5'}};
            app.UITable.ColumnName = ColumnNames;
            app.UITable.Data = lineset_strdef;
            app.UITable.ColumnEditable = [false, true,true,true];
            app.FontDropDown.Items = {'Courier','Geneva','Helvetica','Monaco','Times'};
            app.FontsizeDropDown.Items = {'4','6','8','10','12','14','16','18','20','24','28','32','36',...
                '40','50','60','70','80','90','100'};
            app.FontsizeDropDown.BackgroundColor = [1 1 1];
            app.FontDropDown.BackgroundColor = [1 1 1];
            for Numoflegend = 1:100
                columnStr{Numoflegend} = num2str(Numoflegend);
            end
            app.columnDropDown.Items = columnStr;
            app.columnDropDown.BackgroundColor = [1 1 1];
            if linAutoValue==1
                app.AutoButton.Value=1;
                app.CustomButton.Value=0;
                app.UITable.Enable = 'off';
            else
                app.AutoButton.Value=0;
                app.CustomButton.Value=1;
                app.UITable.Enable = 'on';
            end

            %%legend
            try legendparas = varargin{2}; catch legendparas = [];end
            try legendauto = legendparas{1}; catch legendauto=1; end
            if legendauto==1
                app.AutoButton_2.Value=1;
                app.CustomButton_2.Value=0;
            else
                app.AutoButton_2.Value=0;
                app.CustomButton_2.Value=1;
            end
            try fontnames = legendparas{2};catch fontnames = 'Helvetica';end
            if isempty(fontnames)
               fontnames = 'Helvetica'; 
            end
            [C,IA] =ismember_bc2(fontnames,{'Courier','Geneva','Helvetica','Monaco','Times'});
            if IA==0;
                fontnames = 'Helvetica';
            end
            try fontsizes = legendparas{3};catch fontsizes = '12';end
            if isempty(fontsizes)
              fontsizes = '12';  
            end
            [C1,IA1] =ismember_bc2(fontsizes,{'4','6','8','10','12','14','16','18','20','24','28','32','36',...
                '40','50','60','70','80','90','100'});
            if IA1==0;
                fontsizes = '12';
            end

            try columnsnum = legendparas{4};catch columnsnum = '2';end
            if isempty(columnsnum)
                columnsnum = '2';
            end
            [C2,IA2] =ismember_bc2(columnsnum,columnStr);
            if IA2==0;
                columnsnum = '2';
            else
               columnsnum = columnStr{IA2};  
            end

            try legendcolors = legendparas{5};catch legendcolors=1;end

            if  app.AutoButton_2.Value==1
                enableflag = 'off';
                app.FontDropDown.Value='Courier';
                app.FontsizeDropDown.Value='12';
                app.AutoButton_3.Value=1;
                app.SameaslinesButton.Value=0;
            else
                enableflag = 'on';
                app.FontDropDown.Value= fontnames;
                app.FontsizeDropDown.Value=fontsizes;
                app.AutoButton_3.Value=legendcolors;
                app.SameaslinesButton.Value=~legendcolors;
            end
            app.columnDropDown.Value = columnsnum;

            app.FontDropDown.Enable = enableflag;
            app.FontsizeDropDown.Enable = enableflag;
            app.columnDropDown.Enable = enableflag;
            app.TextcolorButtonGroup.Enable = enableflag;

            app.Finishbutton = 0;
            app.Output = [];
        end

        % Callback function
        function reset_erpname(app, event)

        end

        % Button pushed function: CancelButton
        function cancel(app, event)
            app.Finishbutton = 1;
            app.Output = [];
        end

        % Button pushed function: ApplyButton
        function apply(app, event)

            Lineparas{1} =app.AutoButton.Value;
            Lineparas{2} =app.UITable.Data;
            
            legendparas{1} = app.AutoButton_2.Value;
            legendparas{2} =  app.FontDropDown.Value;
            legendparas{3} =  app.FontsizeDropDown.Value;
            legendparas{4} =  app.columnDropDown.Value;
            legendparas{5} =   app.AutoButton_3.Value;

            app.Finishbutton = 1;
            app.Output = {Lineparas,legendparas};
        end

        % Cell edit callback: UITable
        function cell_edit_changes(app, event)
            indices = event.Indices;
            newData = event.NewData;
        end

        % Selection changed function: LegendButtonGroup
        function legend_changs(app, event)

            if  app.AutoButton_2.Value==1
                enableflag = 'off';
                app.FontDropDown.Value='Courier';
                app.FontsizeDropDown.Value='12';
                app.AutoButton_3.Value=1;
                app.SameaslinesButton.Value=0;
            else
                enableflag = 'on';
            end

            app.FontDropDown.Enable = enableflag;
            app.FontsizeDropDown.Enable = enableflag;
            app.columnDropDown.Enable = enableflag;
            app.TextcolorButtonGroup.Enable = enableflag;
        end

        % Selection changed function: LineSettingsButtonGroup
        function linsetsel(app, event)
            [lineNameStr,linecolors,linetypes,linewidths,linecolors_str,linetypes_str,linewidths_str,linecolorsrgb] = f_get_lineset_ERPviewer();
            lineset_str  =table(lineNameStr,linecolors,linetypes,linewidths);
            lineset_str = table2cell(lineset_str);
            if app.AutoButton.Value==1
                app.UITable.Enable = 'off';
                app.UITable.Data = lineset_str;
            else
                app.UITable.Enable = 'on';
                for ii = 1:length(linecolorsrgb)
                    app.UITable.Data{ii,2} = linecolorsrgb{ii};
                end
            end
            app.UITable.ColumnFormat = {'char', 'char',...
                {'solid','dash','dot','dashdot','plus','circle','asterisk'},...
                {'0.25','0.5','1','1.5','2','2.5','3','3.5','4','4.5','5','5.5','6','6.5','7','7.5','8','8.5','9','9.5','10','10.5'}};

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Original erpname'; 'New erpname'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @cell_edit_changes, true);
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @apply, true);
            app.ApplyButton.Text = 'Apply';
            app.LineSettingsButtonGroup = uibuttongroup(app.UIFigure);
            app.LineSettingsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @linsetsel, true);
            app.LineSettingsButtonGroup.Title = 'Line Settings';
            app.AutoButton = uiradiobutton(app.LineSettingsButtonGroup);
            app.AutoButton.Text = 'Auto';
            app.AutoButton.Value = true;
            app.CustomButton = uiradiobutton(app.LineSettingsButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.SettingsforlegendPanel = uipanel(app.UIFigure);
            app.SettingsforlegendPanel.Title = 'Settings for legend';
            app.LegendButtonGroup = uibuttongroup(app.SettingsforlegendPanel);
            app.LegendButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @legend_changs, true);
            app.LegendButtonGroup.Title = 'Legend';
            app.AutoButton_2 = uiradiobutton(app.LegendButtonGroup);
            app.AutoButton_2.Text = 'Auto';
            app.AutoButton_2.Value = true;
            app.CustomButton_2 = uiradiobutton(app.LegendButtonGroup);
            app.CustomButton_2.Text = 'Custom';
            app.FontDropDownLabel = uilabel(app.SettingsforlegendPanel);
            app.FontDropDownLabel.Text = 'Font';
            app.FontDropDown = uidropdown(app.SettingsforlegendPanel);
            app.FontsizeDropDownLabel = uilabel(app.SettingsforlegendPanel);
            app.FontsizeDropDownLabel.Text = 'Font size';
            app.FontsizeDropDown = uidropdown(app.SettingsforlegendPanel);
            app.TextcolorButtonGroup = uibuttongroup(app.SettingsforlegendPanel);
            app.TextcolorButtonGroup.Title = 'Text color';
            app.AutoButton_3 = uiradiobutton(app.TextcolorButtonGroup);
            app.AutoButton_3.Text = 'Auto';
            app.AutoButton_3.Value = true;
            app.SameaslinesButton = uiradiobutton(app.TextcolorButtonGroup);
            app.SameaslinesButton.Text = 'Same as lines';
            app.columnDropDown = uidropdown(app.SettingsforlegendPanel);
            app.columnDropDownLabel = uilabel(app.SettingsforlegendPanel);
            app.columnDropDownLabel.Text = 'Columns:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Decode_mvpc_line_legend_ops(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end