classdef estudio_decode_mvpa_ops < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ParallelizationButtonGroup    matlab.ui.container.ButtonGroup
        NoButton                      matlab.ui.control.RadioButton
        YesButton                     matlab.ui.control.RadioButton
        TimeParametersPanel           matlab.ui.container.Panel
        Timepoint0msisalwaysincludedLabel  matlab.ui.control.Label
        EffectiveSamplingRateHzLabel  matlab.ui.control.Label
        EditField_2                   matlab.ui.control.NumericEditField
        DecodeeveryTmsSpinner         matlab.ui.control.Spinner
        DecodeeveryTmsSpinnerLabel    matlab.ui.control.Label
        SubsampleOptionalCheckBox     matlab.ui.control.CheckBox
        DecodingTimeRangeButtonGroup  matlab.ui.container.ButtonGroup
        minmaxinmsLabel               matlab.ui.control.Label
        EditField                     matlab.ui.control.NumericEditField
        CustomButton                  matlab.ui.control.RadioButton
        PostButton                    matlab.ui.control.RadioButton
        PreButton                     matlab.ui.control.RadioButton
        AllButton                     matlab.ui.control.RadioButton
        CodingSVMOnlyButtonGroup      matlab.ui.container.ButtonGroup
        OnevsOneButton                matlab.ui.control.RadioButton
        OnevsAllButton                matlab.ui.control.RadioButton
        MethodButtonGroup             matlab.ui.container.ButtonGroup
        CrossvalidatedMahalanobisButton  matlab.ui.control.RadioButton
        SVMButton                     matlab.ui.control.RadioButton
        OkayButton                    matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
    end


    properties (Access = public)
        output % Description
        Finishbutton % Description
        BEST % Description
        BEST_working % Description
    end

    methods (Access = private)


    end

    methods (Access = public)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            painterplabapp(app);
            setfonterplab(app);
            % Name & version
            %
            version = geterplabversion;
            set(app.UIFigure,'Name', ['ERPLAB ' version '   -   EStudio reset parameters GUI']);
            try
                BEST = varargin{1};
            catch
                BEST = [];
            end

            app.EditField_2.Enable = 'off';
            app.DecodeeveryTmsSpinner.BackgroundColor = [1 1 1];
            if isempty(BEST)
                app.MethodButtonGroup.Enable = 'off';
                app.CodingSVMOnlyButtonGroup.Enable = 'off';
                app.TimeParametersPanel.Enable = 'off';
                app.ParallelizationButtonGroup.Enable = 'off';
                return;
            end
            app.BEST = BEST;
            app.BEST_working.xmin = BEST.xmin;
            app.BEST_working.xmax = BEST.xmax;
            app.BEST_working.srate = BEST.srate;
            app.BEST_working.pnts = BEST.pnts;
            app.BEST_working.times = BEST.times;
            app.BEST_working.epoch_window = [BEST.times(1),BEST.times(end)];


        end

        % Button pushed function: CancelButton
        function cancel_button(app, event)
            app.output =[];
            app.Finishbutton = 1;
        end

        % Button pushed function: OkayButton
        function button_continue(app, event)
            resetparas(1)= app.RestoredefaultparametersforEEGpanelsCheckBox.Value;
            resetparas(2)=app.ClearallEEGdatasetsCheckBox.Value;
            resetparas(3)= app.RestoredefaultparametersforERPpanelsCheckBox.Value;
            resetparas(4)=app.ClearallERPdatasetsCheckBox.Value;
            app.output =resetparas;
            app.Finishbutton = 1;
        end

        % Value changed function: EditField
        function EpochRangeValueChanged(app, event)
            value = app.EditField.Value;

        end

        % Selection changed function: DecodingTimeRangeButtonGroup
        function time_range_select(app, event)
            selectedButton = app.DecodingTimeRangeButtonGroup.SelectedObject;
            if strcmpi(selectedButton.Text,'custom')
                app.EditField.Enable = 'on';
            else
                app.EditField.Enable = 'off';
            end

        end

        % Value changed function: DecodeeveryTmsSpinner
        function TPspinnerValueChanged(app, event)
            value = app.DecodeeveryTmsSpinner.Value;

        end

        % Value changed function: SubsampleOptionalCheckBox
        function SubsampleOptionalCheckBoxValueChanged(app, event)
            value = app.SubsampleOptionalCheckBox.Value;
            if value==1
                app.DecodeeveryTmsSpinner.Enable = 'on';
            else
                app.DecodeeveryTmsSpinner.Enable = 'off';
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancel_button, true);
            app.CancelButton.Text = 'Cancel';
            app.OkayButton = uibutton(app.UIFigure, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @button_continue, true);
            app.OkayButton.Text = 'Okay';
            app.MethodButtonGroup = uibuttongroup(app.UIFigure);
            app.MethodButtonGroup.Title = 'Method';
            app.SVMButton = uiradiobutton(app.MethodButtonGroup);
            app.SVMButton.Text = 'SVM';
            app.SVMButton.Value = true;
            app.CrossvalidatedMahalanobisButton = uiradiobutton(app.MethodButtonGroup);
            app.CrossvalidatedMahalanobisButton.Text = 'Crossvalidated Mahalanobis';
            app.CodingSVMOnlyButtonGroup = uibuttongroup(app.UIFigure);
            app.CodingSVMOnlyButtonGroup.Title = 'Coding (SVM Only)';
            app.OnevsAllButton = uiradiobutton(app.CodingSVMOnlyButtonGroup);
            app.OnevsAllButton.Text = 'One vs All';
            app.OnevsAllButton.Value = true;
            app.OnevsOneButton = uiradiobutton(app.CodingSVMOnlyButtonGroup);
            app.OnevsOneButton.Text = 'One vs One';
            app.TimeParametersPanel = uipanel(app.UIFigure);
            app.TimeParametersPanel.Title = 'Time Parameters';
            app.DecodingTimeRangeButtonGroup = uibuttongroup(app.TimeParametersPanel);
            app.DecodingTimeRangeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @time_range_select, true);
            app.DecodingTimeRangeButtonGroup.Title = 'Decoding Time Range';
            app.AllButton = uiradiobutton(app.DecodingTimeRangeButtonGroup);
            app.AllButton.Text = 'All';
            app.AllButton.Value = true;
            app.PreButton = uiradiobutton(app.DecodingTimeRangeButtonGroup);
            app.PreButton.Text = 'Pre';
            app.PostButton = uiradiobutton(app.DecodingTimeRangeButtonGroup);
            app.PostButton.Text = 'Post';
            app.CustomButton = uiradiobutton(app.DecodingTimeRangeButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.EditField = uieditfield(app.DecodingTimeRangeButtonGroup, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EpochRangeValueChanged, true);
            app.minmaxinmsLabel = uilabel(app.DecodingTimeRangeButtonGroup);
            app.minmaxinmsLabel.Text = '(min, max in ms)';
            app.SubsampleOptionalCheckBox = uicheckbox(app.TimeParametersPanel);
            app.SubsampleOptionalCheckBox.ValueChangedFcn = createCallbackFcn(app, @SubsampleOptionalCheckBoxValueChanged, true);
            app.SubsampleOptionalCheckBox.Text = 'Subsample (Optional)';
            app.DecodeeveryTmsSpinnerLabel = uilabel(app.TimeParametersPanel);
            app.DecodeeveryTmsSpinnerLabel.Text = 'Decode every T (ms)';
            app.DecodeeveryTmsSpinner = uispinner(app.TimeParametersPanel);
            app.DecodeeveryTmsSpinner.ValueChangedFcn = createCallbackFcn(app, @TPspinnerValueChanged, true);
            app.EditField_2 = uieditfield(app.TimeParametersPanel, 'numeric');
            app.EffectiveSamplingRateHzLabel = uilabel(app.TimeParametersPanel);
            app.EffectiveSamplingRateHzLabel.Text = 'Effective Sampling Rate (Hz)';
            app.Timepoint0msisalwaysincludedLabel = uilabel(app.TimeParametersPanel);
            app.Timepoint0msisalwaysincludedLabel.Text = 'Time-point 0 (ms) is always included';
            app.ParallelizationButtonGroup = uibuttongroup(app.UIFigure);
            app.ParallelizationButtonGroup.Title = 'Parallelization';
            app.YesButton = uiradiobutton(app.ParallelizationButtonGroup);
            app.YesButton.Text = 'Yes';
            app.YesButton.Value = true;
            app.NoButton = uiradiobutton(app.ParallelizationButtonGroup);
            app.NoButton.Text = 'No';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = estudio_decode_mvpa_ops(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end