classdef dq_trial_rejection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ExporttrialinformationButton  matlab.ui.control.Button
        TextArea                      matlab.ui.control.TextArea
        ERPNameLabel                  matlab.ui.control.Label
        CloseButton                   matlab.ui.control.Button
        Edit_pos_width_4              matlab.ui.control.EditField
        Button_2                      matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        TrialInformationLabel         matlab.ui.control.Label
        oftrialswererejectedLabel     matlab.ui.control.Label
        Edit_pos_width_3              matlab.ui.control.EditField
        asme_table1                   matlab.ui.control.Label
        UITable_2                     matlab.ui.control.Table
        UITable                       matlab.ui.control.Table
        EpochedEEGLabel               matlab.ui.control.Label
        Edit_pos_width_2              matlab.ui.control.EditField
        Edit_pos_width                matlab.ui.control.EditField
        OutputLabel                   matlab.ui.control.Label
        InputLabel                    matlab.ui.control.Label
    end


    properties (Access = public)
        Finishbutton % Description
        Output % Description
        EEGname_indx % Description
    end

    properties (Access = private)
        ALLEEG % Description
        ALLERP % Description
        ERPset % Description
        ERPArray % Description
    end

    methods (Access = private)

        function results = func_updated(app)
            ALLERP = app.ALLERP;
            ALLEEG = app.ALLEEG;
            try ERPindex  = app.ERPArray(app.ERPset);catch ERPindex=1; end
            if ERPindex> length(ALLERP)
                ERPindex=length(ALLERP);app.ERPArray = ERPindex;
            end
            ERP = ALLERP(ERPindex);
            for ii = 1:length(ERP.ntrials.accepted)
                try
                    data{ii,1} = [num2str(ii),'.',ERP.bindescr{ii}];
                    data{ii,2} = [num2str(ERP.ntrials.accepted(ii)+ERP.ntrials.rejected(ii)+ERP.ntrials.invalid(ii))];
                    data{ii,3} =      [num2str(ERP.ntrials.accepted(ii)),32,'(',num2str(roundn(100*ERP.ntrials.accepted(ii)/(ERP.ntrials.accepted(ii)+ERP.ntrials.rejected(ii)+ERP.ntrials.invalid(ii)),-1)),32,'%)'];
                    data{ii,4} =     [num2str(ERP.ntrials.rejected(ii)),32,'(',num2str(roundn(100*ERP.ntrials.rejected(ii)/(ERP.ntrials.accepted(ii)+ERP.ntrials.rejected(ii)+ERP.ntrials.invalid(ii)),-1)),32,'%)'];
                    data{ii,5} =     [num2str(ERP.ntrials.invalid(ii)),32,'(',num2str(roundn(100*ERP.ntrials.invalid(ii)/(ERP.ntrials.accepted(ii)+ERP.ntrials.rejected(ii)+ERP.ntrials.invalid(ii)),-1)),32,'%)'];
                    histoflags = fliplr(ERP.ntrials.arflags);
                    data{ii,6} = num2str(histoflags(ii,2));
                    data{ii,7} = num2str(histoflags(ii,3));
                    data{ii,8} = num2str(histoflags(ii,4));
                    data{ii,9} = num2str(histoflags(ii,5));
                    data{ii,10} = num2str(histoflags(ii,6));
                    data{ii,11} = num2str(histoflags(ii,7));
                    data{ii,12} = num2str(histoflags(ii,8));
                catch
                    data{ii,1} = '';
                    data{ii,2} =     '';
                    data{ii,3} =      '';
                    data{ii,4} =      '';
                    data{ii,5} = '';
                    data{ii,6} = '';
                    data{ii,7} = '';
                    data{ii,8} = '';
                    data{ii,9} = '';
                    data{ii,10} = '';
                    data{ii,11} = '';
                    data{ii,12} = '';
                end
            end
            app.UITable.Data = data;
            rejectionpec = sum(ERP.ntrials.rejected(:))/(sum(ERP.ntrials.accepted(:))+ sum(ERP.ntrials.rejected(:))+sum(ERP.ntrials.invalid(:)));
            app.Edit_pos_width_3.Value = num2str(roundn(100*rejectionpec,-2));

            try app.Edit_pos_width.Value = ALLEEG{app.ERPset}; catch app.Edit_pos_width.Value = '';end

            try app.Edit_pos_width_2.Value = ERP.erpname; catch app.Edit_pos_width_2.Value ='';end

            %%
            try
                ERP_SME_summary = f_dq_summary(ERP);
            catch
                ERP_SME_summary = cell(3,3);
            end
            try Data1{1,1} = num2str(roundn(ERP_SME_summary{1,1},-2));catch Data1{1,1} = '';end
            try Data1{1,2} = ERP.chanlocs(ERP_SME_summary{2,1}).labels;catch Data1{1,2} = '';end
            try Median_tw =ERP_SME_summary{3,1};catch Median_tw = [0 0]; end
            try Data1{1,3} = [num2str(Median_tw(1)),'-',num2str(Median_tw(2)),32,'ms'];catch Data1{1,3} ='';  end
            try Data1{1,4} = ['Bin',num2str(ERP_SME_summary{4,1})]; catch  Data1{1,4} =''; end
            %%min
            try Data1{2,1} = num2str(roundn(ERP_SME_summary{1,2},-2));catch Data1{2,1} = '';end
            try Data1{2,2} = ERP.chanlocs(ERP_SME_summary{2,2}).labels;catch Data1{2,2} = '';end
            try Median_tw =ERP_SME_summary{3,2};catch Median_tw = [0 0]; end
            try Data1{2,3} = [num2str(Median_tw(1)),'-',num2str(Median_tw(2)),32,'ms'];catch Data1{2,3} ='';  end
            try Data1{2,4} = ['Bin',num2str(ERP_SME_summary{4,2})]; catch  Data1{2,4} =''; end

            %%min
            try Data1{3,1} = num2str(roundn(ERP_SME_summary{1,3},-2));catch Data1{3,1} = '';end
            try Data1{3,2} = ERP.chanlocs(ERP_SME_summary{2,3}).labels;catch Data1{3,2} = '';end
            try Median_tw =ERP_SME_summary{3,3};catch Median_tw = [0 0]; end
            try Data1{3,3} = [num2str(Median_tw(1)),'-',num2str(Median_tw(2)),32,'ms'];catch Data1{3,3} ='';  end
            try Data1{3,4} = ['Bin',num2str(ERP_SME_summary{4,3})]; catch  Data1{3,4} =''; end
            app.UITable_2.Data = Data1;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            painterplabapp(app);
            setfonterplab(app);
            % Name & version
            %
            app.Edit_pos_width_3.BackgroundColor = [1 1 1];
            version = geterplabversion;
            set(app.UIFigure,'Name', ['Estudio ' version '   - ERP trial information and SME GUI']);
            try
                app.ALLERP = varargin{1};

            catch
                app.ALLERP = [];
            end
            try app.ERPArray=varargin{2};catch app.ERPArray=1; end

            try
                app.ALLEEG = varargin{3};
            catch
                app.ALLERP = [];
            end

            if isempty(app.ALLERP)
                app.Edit_pos_width_2.Value = 'Please input validate ERPsets';
                return;
            end
            app.Edit_pos_width_2.BackgroundColor = [1 1 1];
            app.Edit_pos_width.BackgroundColor = [1 1 1];
            %app.Edit_pos_width.FontColor = [1 0 0];
            if  ~isempty(app.ALLERP)
                app.Edit_pos_width_4.Value='1';
                app.ERPset=1;
                func_updated(app);
            end
            app.Finishbutton = 0;
            app.Output = [];
        end

        % Value changed function: Edit_pos_width
        function eeg_name(app, event)


        end

        % Value changed function: Edit_pos_width_2
        function erp_name(app, event)


        end

        % Display data changed function: UITable
        function trial_rejection_table(app, event)
            newDisplayData = app.UITable.DisplayData;
        end

        % Display data changed function: UITable_2
        function asme_table(app, event)
            newDisplayData = app.UITable_2.DisplayData;
        end

        % Value changed function: Edit_pos_width_3
        function rejected_trials(app, event)

        end

        % Button pushed function: Button
        function pre_erpset(app, event)

            ERPset = str2num(app.Edit_pos_width_4.Value);
            ERPset = ERPset-1;
            if isempty(ERPset)|| any(ERPset<=0) || numel(ERPset)~=1 || any(ERPset>length(app.ERPArray))
                ERPset=1;
                msgboxText = ['The ERPset should be between 1 and ',32,num2str(length(app.ERPArray))];
                app.TextArea.Value = msgboxText;
            end
            app.ERPset =ERPset;
            app.Edit_pos_width_4.Value =num2str(ERPset);
            func_updated(app);
        end

        % Button pushed function: Button_2
        function next_erpset(app, event)
            ERPset = str2num(app.Edit_pos_width_4.Value);
            ERPset = ERPset+1;
            if isempty(ERPset)|| any(ERPset<=0) || numel(ERPset)~=1 || any(ERPset>length(app.ERPArray))
                ERPset=length(app.ERPArray);
                msgboxText = ['The ERPset should be between 1 and ',32,num2str(length(app.ERPArray))];
                app.TextArea.Value = msgboxText;
            end
            app.ERPset =ERPset;
            app.Edit_pos_width_4.Value =num2str(ERPset);
            func_updated(app);
        end

        % Value changed function: Edit_pos_width_4
        function erpset_edit(app, event)
            ERPset = str2num(app.Edit_pos_width_4.Value);
            ERPset = ERPset+1;
            if isempty(ERPset)|| any(ERPset<=0) || numel(ERPset)~=1 || any(ERPset>length(app.ERPArray))
                ERPset=1;
                msgboxText = ['The ERPset should be between 1 and ',32,num2str(length(app.ERPArray))];
                app.TextArea.Value = msgboxText;
            end
            app.ERPset =ERPset;
            app.Edit_pos_width_4.Value =num2str(ERPset);
            func_updated(app);
        end

        % Button pushed function: CloseButton
        function exit(app, event)
            app.Finishbutton = 1;
            app.Output = [];
            app.delete;
        end

        % Value changed function: TextArea
        function error_message(app, event)
            value = app.TextArea.Value;

        end

        % Button pushed function: ExporttrialinformationButton
        function export_reject(app, event)
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            ALLERP = app.ALLERP;
            erpcom =  pop_ERP_save_trial_information(ALLERP, 'ERPArray',app.ERPArray);
            try
                ALLERPCOM = evalin('base','ALLERPCOM');
            catch
                ALLERPCOM = [];
                assignin('base','ALLERPCOM',ALLERPCOM);
            end
            if isempty(ALLERPCOM)
                ALLERPCOM{1} = erpcom;
            else
                ALLERPCOM{end+1} = erpcom;
            end
            assignin('base','ALLERPCOM',ALLERPCOM);
            msgboxText = ['It is done for exporting trial information'];
            app.TextArea.Value = msgboxText;
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputLabel = uilabel(app.UIFigure);
            app.InputLabel.Text = 'Input:';
            app.OutputLabel = uilabel(app.UIFigure);
            app.OutputLabel.Text = 'Output:';
            app.Edit_pos_width = uieditfield(app.UIFigure, 'text');
            app.Edit_pos_width.ValueChangedFcn = createCallbackFcn(app, @eeg_name, true);
            app.Edit_pos_width.Editable = 'off';
            app.Edit_pos_width_2 = uieditfield(app.UIFigure, 'text');
            app.Edit_pos_width_2.ValueChangedFcn = createCallbackFcn(app, @erp_name, true);
            app.Edit_pos_width_2.Editable = 'off';
            app.EpochedEEGLabel = uilabel(app.UIFigure);
            app.EpochedEEGLabel.Text = 'Epoched EEG';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Bin'; 'Total trials'; 'Included trials'; 'Rejected trials'; 'Invalid trials'; 'F2'; 'F3'; 'F4'; 'F5'; 'F6'; 'F7'; 'F8'};
            app.UITable.RowName = {};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @trial_rejection_table, true);
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'aSME'; 'Channel'; 'Time window'; 'Bin'};
            app.UITable_2.RowName = {'Median'; 'Min'; 'Max'};
            app.UITable_2.DisplayDataChangedFcn = createCallbackFcn(app, @asme_table, true);
            app.asme_table1 = uilabel(app.UIFigure);
            app.asme_table1.Text = 'Analytic Standardized Measurement Error (aSME)';
            app.Edit_pos_width_3 = uieditfield(app.UIFigure, 'text');
            app.Edit_pos_width_3.ValueChangedFcn = createCallbackFcn(app, @rejected_trials, true);
            app.Edit_pos_width_3.Editable = 'off';
            app.oftrialswererejectedLabel = uilabel(app.UIFigure);
            app.oftrialswererejectedLabel.Text = '% of  trials were rejected';
            app.TrialInformationLabel = uilabel(app.UIFigure);
            app.TrialInformationLabel.Text = 'Trial Information';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @pre_erpset, true);
            app.Button.Text = '<';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @next_erpset, true);
            app.Button_2.Text = '>';
            app.Edit_pos_width_4 = uieditfield(app.UIFigure, 'text');
            app.Edit_pos_width_4.ValueChangedFcn = createCallbackFcn(app, @erpset_edit, true);
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @exit, true);
            app.CloseButton.Text = 'Close';
            app.ERPNameLabel = uilabel(app.UIFigure);
            app.ERPNameLabel.Text = 'ERP Name';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @error_message, true);
            app.ExporttrialinformationButton = uibutton(app.UIFigure, 'push');
            app.ExporttrialinformationButton.ButtonPushedFcn = createCallbackFcn(app, @export_reject, true);
            app.ExporttrialinformationButton.Text = 'Export trial information';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dq_trial_rejection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end