classdef estudio_reset_paras < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ContinueButton               matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
        ClearallERPdatasetsCheckBox  matlab.ui.control.CheckBox
        RestoredefaultparametersforERPpanelsCheckBox  matlab.ui.control.CheckBox
        ClearallEEGdatasetsCheckBox  matlab.ui.control.CheckBox
        RestoredefaultparametersforEEGpanelsCheckBox  matlab.ui.control.CheckBox
    end


    properties (Access = public)
        output % Description
        Finishbutton % Description
    end

    methods (Access = private)


    end

    methods (Access = public)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            painterplabapp(app);
            setfonterplab(app);
            % Name & version
            %
            version = geterplabversion;
            set(app.UIFigure,'Name', ['ERPLAB ' version '   -   EStudio reset parameters GUI']);
            try
                resetparas = varargin{1};
            catch
                resetparas = [0 0 0 0];
            end
            if isempty(resetparas) || numel(resetparas)~=4
                resetparas = [0 0 0 0];
            end
            app.RestoredefaultparametersforEEGpanelsCheckBox.Value = resetparas(1);
            app.ClearallEEGdatasetsCheckBox.Value = resetparas(2);
            app.RestoredefaultparametersforERPpanelsCheckBox.Value = resetparas(3);
            app.ClearallERPdatasetsCheckBox.Value = resetparas(4);
            try
                resetparas1 = varargin{2};
            catch
                resetparas1 = [1 1];
            end
            if resetparas1(1)==0
                app.RestoredefaultparametersforEEGpanelsCheckBox.Value = 0;
                app.ClearallEEGdatasetsCheckBox.Value = 0;
                app.RestoredefaultparametersforEEGpanelsCheckBox.Enable = 'off';
                app.ClearallEEGdatasetsCheckBox.Enable = 'off';
            else
                app.RestoredefaultparametersforEEGpanelsCheckBox.Enable = 'on';
                app.ClearallEEGdatasetsCheckBox.Enable = 'on';
            end
            if resetparas1(2)==0
                app.RestoredefaultparametersforERPpanelsCheckBox.Value = 0;
                app.ClearallERPdatasetsCheckBox.Value = 0;
                app.RestoredefaultparametersforERPpanelsCheckBox.Enable = 'off';
                app.ClearallERPdatasetsCheckBox.Enable = 'off';
            else
                app.RestoredefaultparametersforERPpanelsCheckBox.Enable = 'on';
                app.ClearallERPdatasetsCheckBox.Enable = 'on';
            end

        end

        % Value changed function: 
        % RestoredefaultparametersforEEGpanelsCheckBox
        function eeg_panel_paras(app, event)
            value = app.RestoredefaultparametersforEEGpanelsCheckBox.Value;

        end

        % Value changed function: ClearallEEGdatasetsCheckBox
        function cler_alleeg(app, event)
            value = app.ClearallEEGdatasetsCheckBox.Value;

        end

        % Value changed function: 
        % RestoredefaultparametersforERPpanelsCheckBox
        function erp_panel_paras(app, event)
            value = app.RestoredefaultparametersforERPpanelsCheckBox.Value;

        end

        % Value changed function: ClearallERPdatasetsCheckBox
        function clear_allerp(app, event)
            value = app.ClearallERPdatasetsCheckBox.Value;

        end

        % Button pushed function: CancelButton
        function cancel_button(app, event)
            app.output =[];
            app.Finishbutton = 1;
        end

        % Button pushed function: ContinueButton
        function button_continue(app, event)
            resetparas(1)= app.RestoredefaultparametersforEEGpanelsCheckBox.Value;
            resetparas(2)=app.ClearallEEGdatasetsCheckBox.Value;
            resetparas(3)= app.RestoredefaultparametersforERPpanelsCheckBox.Value;
            resetparas(4)=app.ClearallERPdatasetsCheckBox.Value;
            app.output =resetparas;
            app.Finishbutton = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RestoredefaultparametersforEEGpanelsCheckBox = uicheckbox(app.UIFigure);
            app.RestoredefaultparametersforEEGpanelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @eeg_panel_paras, true);
            app.RestoredefaultparametersforEEGpanelsCheckBox.Text = 'Restore default parameters for EEG panels';
            app.ClearallEEGdatasetsCheckBox = uicheckbox(app.UIFigure);
            app.ClearallEEGdatasetsCheckBox.ValueChangedFcn = createCallbackFcn(app, @cler_alleeg, true);
            app.ClearallEEGdatasetsCheckBox.Text = 'Clear all EEG datasets';
            app.RestoredefaultparametersforERPpanelsCheckBox = uicheckbox(app.UIFigure);
            app.RestoredefaultparametersforERPpanelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @erp_panel_paras, true);
            app.RestoredefaultparametersforERPpanelsCheckBox.Text = 'Restore default parameters for ERP panels';
            app.ClearallERPdatasetsCheckBox = uicheckbox(app.UIFigure);
            app.ClearallERPdatasetsCheckBox.ValueChangedFcn = createCallbackFcn(app, @clear_allerp, true);
            app.ClearallERPdatasetsCheckBox.Text = 'Clear all ERP datasets';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancel_button, true);
            app.CancelButton.Text = 'Cancel';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @button_continue, true);
            app.ContinueButton.Text = 'Continue';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = estudio_reset_paras(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end